classdef HRVtools_modified < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HRVenusUIFigure                 matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        Menu_3                          matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        Menu_4                          matlab.ui.container.Menu
        Menu_5                          matlab.ui.container.Menu
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        PushTool11                      matlab.ui.container.toolbar.PushTool
        PushTool3                       matlab.ui.container.toolbar.PushTool
        PushexportPDF                   matlab.ui.container.toolbar.PushTool
        PushTool5                       matlab.ui.container.toolbar.PushTool
        PushTool4                       matlab.ui.container.toolbar.PushTool
        PushTool2                       matlab.ui.container.toolbar.PushTool
        PushTool6                       matlab.ui.container.toolbar.PushTool
        PushTool10                      matlab.ui.container.toolbar.PushTool
        TabGroup3                       matlab.ui.container.TabGroup
        Tab_7                           matlab.ui.container.Tab
        Button_HR                       matlab.ui.control.StateButton
        Button_RR                       matlab.ui.control.StateButton
        Button_delete                   matlab.ui.control.StateButton
        Button_add                      matlab.ui.control.StateButton
        RevButton                       matlab.ui.control.Button
        Spinner                         matlab.ui.control.Spinner
        Button_3                        matlab.ui.control.StateButton
        Button_4                        matlab.ui.control.Button
        Slider                          matlab.ui.control.Slider
        Button_5                        matlab.ui.control.Button
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        HRVButton                       matlab.ui.control.Button
        RRCheckBox                      matlab.ui.control.CheckBox
        CCheckBox                       matlab.ui.control.CheckBox
        BCheckBox                       matlab.ui.control.CheckBox
        ACheckBox                       matlab.ui.control.CheckBox
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        Tab_9                           matlab.ui.container.Tab
        AnaCheckBox                     matlab.ui.control.CheckBox
        XEditField_2                    matlab.ui.control.NumericEditField
        XEditField_2Label               matlab.ui.control.Label
        Button_7                        matlab.ui.control.Button
        Slider_2                        matlab.ui.control.Slider
        Button_6                        matlab.ui.control.Button
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        TabGroup2                       matlab.ui.container.TabGroup
        Tab_5                           matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        REditField_2                    matlab.ui.control.EditField
        REditField_2Label               matlab.ui.control.Label
        REditField                      matlab.ui.control.EditField
        REditFieldLabel                 matlab.ui.control.Label
        EditField_16                    matlab.ui.control.NumericEditField
        Label_18                        matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        EditField_13Label               matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        EditField_12Label               matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        Label_16                        matlab.ui.control.Label
        Label_32                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        HzEditField                     matlab.ui.control.NumericEditField
        HzEditFieldLabel                matlab.ui.control.Label
        fileLabel                       matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Tab_6                           matlab.ui.container.Tab
        GridLayout_2                    matlab.ui.container.GridLayout
        hhmmssLabel_2                   matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        fileLabel_2                     matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        EditField_15                    matlab.ui.control.EditField
        EditField_15Label               matlab.ui.control.Label
        EditField_14                    matlab.ui.control.EditField
        EditField_14Label               matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        HzEditField_2                   matlab.ui.control.NumericEditField
        HzEditField_2Label              matlab.ui.control.Label
        folderLabel                     matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        SDNNiminEditField               matlab.ui.control.NumericEditField
        SDNNiminEditFieldLabel          matlab.ui.control.Label
        pNNxsEditField                  matlab.ui.control.NumericEditField
        pNNxsEditFieldLabel             matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        FFTTab                          matlab.ui.container.Tab
        OverlapEditField                matlab.ui.control.NumericEditField
        OverlapEditFieldLabel           matlab.ui.control.Label
        WidthptsEditField               matlab.ui.control.NumericEditField
        WidthptsEditFieldLabel          matlab.ui.control.Label
        PointsinPSDEditField            matlab.ui.control.NumericEditField
        PointsinPSDEditFieldLabel       matlab.ui.control.Label
        InterpolationRateHzEditField    matlab.ui.control.NumericEditField
        InterpolationRateHzLabel        matlab.ui.control.Label
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_7Label                matlab.ui.control.Label
        HFEditField                     matlab.ui.control.NumericEditField
        HFEditFieldLabel                matlab.ui.control.Label
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_6Label                matlab.ui.control.Label
        LFEditField                     matlab.ui.control.NumericEditField
        LFEditFieldLabel                matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        Label_9                         matlab.ui.control.Label
        VLFEditField                    matlab.ui.control.NumericEditField
        VLFEditFieldLabel               matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        UIAxes2_3                       matlab.ui.control.UIAxes
        ARTab                           matlab.ui.container.Tab
        BMOEditField_2                  matlab.ui.control.NumericEditField
        BMOEditField_2Label             matlab.ui.control.Label
        PointsinPSDEditField_2          matlab.ui.control.NumericEditField
        PointsinPSDEditField_2Label     matlab.ui.control.Label
        InterpolationRateHzEditField_2  matlab.ui.control.NumericEditField
        InterpolationRateHzEditField_2Label  matlab.ui.control.Label
        EditField_11                    matlab.ui.control.NumericEditField
        EditField_11Label               matlab.ui.control.Label
        HFEditField_2                   matlab.ui.control.NumericEditField
        HFEditField_2Label              matlab.ui.control.Label
        EditField_10                    matlab.ui.control.NumericEditField
        EditField_10Label               matlab.ui.control.Label
        LFEditField_2                   matlab.ui.control.NumericEditField
        LFEditField_2Label              matlab.ui.control.Label
        EditField_9                     matlab.ui.control.NumericEditField
        EditField_9Label                matlab.ui.control.Label
        VLFEditField_2                  matlab.ui.control.NumericEditField
        VLFEditField_2Label             matlab.ui.control.Label
        UITable_3                       matlab.ui.control.Table
        UIAxes2_6                       matlab.ui.control.UIAxes
        Tab_4                           matlab.ui.container.Tab
        BreakPointEditField             matlab.ui.control.NumericEditField
        BreakPointEditFieldLabel        matlab.ui.control.Label
        EditField_8                     matlab.ui.control.NumericEditField
        EditField_8Label                matlab.ui.control.Label
        DFAnEditField                   matlab.ui.control.NumericEditField
        DFAnEditFieldLabel              matlab.ui.control.Label
        mEditField                      matlab.ui.control.NumericEditField
        mEditFieldLabel                 matlab.ui.control.Label
        SampEnrEditField                matlab.ui.control.NumericEditField
        SampEnrEditFieldLabel           matlab.ui.control.Label
        UITable_4                       matlab.ui.control.Table
        UIAxes2_5                       matlab.ui.control.UIAxes
        UIAxes2_4                       matlab.ui.control.UIAxes
        TimeFreqTab                     matlab.ui.container.Tab
        MethodButtonGroup_W             matlab.ui.container.ButtonGroup
        WaveletButton_W                 matlab.ui.control.RadioButton
        LombScargleButton_W             matlab.ui.control.RadioButton
        BurgButton_W                    matlab.ui.control.RadioButton
        PlotViewButtonGroup             matlab.ui.container.ButtonGroup
        LFHFPowerButton                 matlab.ui.control.ToggleButton
        LFHFRatioButton                 matlab.ui.control.ToggleButton
        WaterfallButton                 matlab.ui.control.ToggleButton
        SurfaceButton                   matlab.ui.control.ToggleButton
        tfOverlapsEditField             matlab.ui.control.NumericEditField
        OverlapsEditFieldLabel          matlab.ui.control.Label
        tfWidowsEditField               matlab.ui.control.NumericEditField
        WidowsEditFieldLabel            matlab.ui.control.Label
        MethodButtonGroup_S             matlab.ui.container.ButtonGroup
        WaveletButton_S                 matlab.ui.control.RadioButton
        LombScargleButton_S             matlab.ui.control.RadioButton
        BurgButton_S                    matlab.ui.control.RadioButton
        UIAxesTF_W                      matlab.ui.control.UIAxes
        UIAxesTF_S                      matlab.ui.control.UIAxes
        IBITab                          matlab.ui.container.Tab
        applyMethod_Button              matlab.ui.control.Button
        ReplacementButtonGroup          matlab.ui.container.ButtonGroup
        sLabel                          matlab.ui.control.Label
        Edit_Median                     matlab.ui.control.EditField
        Edit_Mean                       matlab.ui.control.EditField
        SplineButton                    matlab.ui.control.RadioButton
        MedianButton                    matlab.ui.control.RadioButton
        MeanButton                      matlab.ui.control.RadioButton
        NoneButton                      matlab.ui.control.RadioButton
        DetectionButtonGroup            matlab.ui.container.ButtonGroup
        xLabel                          matlab.ui.control.Label
        Label_33                        matlab.ui.control.Label
        Edit_median                     matlab.ui.control.EditField
        Edit_std                        matlab.ui.control.EditField
        Edit_percent                    matlab.ui.control.EditField
        medianCheckBox                  matlab.ui.control.CheckBox
        stddevCheckBox                  matlab.ui.control.CheckBox
        percentCheckBox                 matlab.ui.control.CheckBox
        UIAxes_SQI                      matlab.ui.control.UIAxes
        Tab_10                          matlab.ui.container.Tab
        UIAxesSNS                       matlab.ui.control.UIAxes
        UIAxesPNS                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingWindow
        newpath = ''; % 记录文件打开位置
        nameList = []; % 批量文件名称列表
        fileName; % 文件名称
        suffix; % 文件后缀名称
        Stime; % 数据时长
        axes1XLim = 0; % 坐标轴1的x轴范围
        axes2XLim = 0; % 坐标轴2的x轴范围
        axes3XLim = 0; % 坐标轴2的x轴范围
        ECG_ori = []; % 原始心电
        PPG_ori = []; %原始血氧
        data_ori = {}; % 无校正数据
        data_correction = {}; % 自动校正数据
        data_update = {}; % 手动校正数据
        updata_Resp = {};%呼吸相关截取信号、频率
        data_A = {};
        data_B = {};
        data_RR = {};
        ifFirstHRV = true; % 是否是初次进行HRV分析
        dataTag = 'normal';
        % 数据类型，普通数据为normal，睡眠数据为sleep(此处已删除)
        
        Resp = []; % 呼吸信号
        SQI = []; % 信号质量
        % 输入参数获取
        input_parameter = {};
        %IBI自动校正算法参数
        IBIvarargin = {};
    end
    
    methods (Access = private)
        %% 获取信息面板参数(文件)
        function getFileParameter(app)
            % 采样率
            app.input_parameter.Fs = app.HzEditField.Value;
            % 分析窗口开始时间
            app.input_parameter.startTime = calcTime(app.EditField.Value, 'str2time');
            % 分析窗口持续时间
            app.input_parameter.windowTime = calcTime(app.EditField_2.Value, 'str2time');
            % 分析窗口滑动步长
            app.input_parameter.step = app.EditField_16.Value;
            % 获取编辑修正模糊范围
            app.input_parameter.correctRange = app.Spinner.Value * app.HzEditField.Value;
            % 获取坐标轴1显示范围
            app.input_parameter.axes1Range = app.XEditField.Value;
            % 获取坐标轴3显示范围
            app.input_parameter.axes3Range = app.XEditField_2.Value;
        end

        %% 获取信息面板参数(文件夹)
        function getFolderParameter(app)
            % 采样率
            app.input_parameter.Fs = app.HzEditField_2.Value;
            % 分析窗口开始时间
            app.input_parameter.startTime = calcTime(app.EditField_14.Value, 'str2time');
            % 分析窗口持续时间
            app.input_parameter.windowTime = calcTime(app.EditField_15.Value, 'str2time');
        end

        %% 获取最新HRV输入参数
        function getHRVParameter(app)
            % 时域输入参数获取
            app.input_parameter.pNNxIndex = app.pNNxsEditField.Value;
            app.input_parameter.SDNNiIndex = 60*app.SDNNiminEditField.Value;

            % 频域输入参数获取-welch
            app.input_parameter.welch.VLF = zeros(1,2);
            app.input_parameter.welch.LF = zeros(1,2);
            app.input_parameter.welch.HF = zeros(1,2);
            app.input_parameter.welch.VLF(1) = app.VLFEditField.Value;
            app.input_parameter.welch.VLF(2) = app.EditField_5.Value;
            app.input_parameter.welch.LF(1) = app.LFEditField.Value;
            app.input_parameter.welch.LF(2) = app.EditField_6.Value;
            app.input_parameter.welch.HF(1) = app.HFEditField.Value;
            app.input_parameter.welch.HF(2) = app.EditField_7.Value;
            app.input_parameter.welch.fs = app.InterpolationRateHzEditField.Value;
            app.input_parameter.welch.nfft = app.PointsinPSDEditField.Value;
            app.input_parameter.welch.window = app.WidthptsEditField.Value;
            app.input_parameter.welch.noverlap = app.OverlapEditField.Value;
            
            % 频域输入参数获取-ar
            app.input_parameter.ar.VLF = zeros(1,2);
            app.input_parameter.ar.LF = zeros(1,2);
            app.input_parameter.ar.HF = zeros(1,2);
            app.input_parameter.ar.VLF(1) = app.VLFEditField_2.Value;
            app.input_parameter.ar.VLF(2) = app.EditField_9.Value;
            app.input_parameter.ar.LF(1) = app.LFEditField_2.Value;
            app.input_parameter.ar.LF(2) = app.EditField_10.Value;
            app.input_parameter.ar.HF(1) = app.HFEditField_2.Value;
            app.input_parameter.ar.HF(2) = app.EditField_11.Value;
            app.input_parameter.ar.fs = app.InterpolationRateHzEditField_2.Value;
            app.input_parameter.ar.nfft = app.PointsinPSDEditField_2.Value;
            app.input_parameter.ar.AR_order = app.BMOEditField_2.Value;
         
            % 非线性输入参数获取
            app.input_parameter.r = app.SampEnrEditField.Value;
            app.input_parameter.m = app.mEditField.Value;
            app.input_parameter.n1 = app.DFAnEditField.Value;
            app.input_parameter.n2 = app.EditField_8.Value;
            app.input_parameter.breakpoint = app.BreakPointEditField.Value;

            %时频输入参数获取
            app.input_parameter.TF.window = app.tfWidowsEditField.Value;
            app.input_parameter.TF.noverlap = app.tfOverlapsEditField.Value;

            
        end

        %% 获取IBI自动校正方法
        function getIBIvararginMethod(app)
            locateMethod =  {};
            if app.percentCheckBox.Value
                locateMethod = {'percent'};
            end
            if app.stddevCheckBox.Value
                locateMethod = [locateMethod , {'sd'}];
            end
            if app.medianCheckBox.Value
                locateMethod = [locateMethod , {'median'}];
            end
            app.IBIvarargin.locateMethod = locateMethod;
            app.IBIvarargin.locateInput = [str2num(app.Edit_percent.Value), str2num(app.Edit_std.Value), str2num(app.Edit_median.Value)]; %#ok<*ST2NM> 
            Selected = app.ReplacementButtonGroup.SelectedObject;
            app.IBIvarargin.replaceMethod = Selected.Text;
            if app.MeanButton.Value
                app.IBIvarargin.replaceInput = app.Edit_Mean.Value;
            elseif app.MedianButton.Value
                app.IBIvarargin.replaceInput = app.Edit_Median.Value;
            else
                app.IBIvarargin.replaceInput = [];
            end
        end
        %% 初次读取呼吸数据更新界面
        function firstLoadResp(app, flag, Resp)
            % 检查数据是否正确
            if flag && ~isempty(Resp)
                % 呼吸滤波
                app.Resp = preProcessResp(Resp, app.input_parameter.Fs);

                %更新呼吸数据
                updataResp(app,app.Resp)

                % 开启坐标轴3,4显示范围修改功能
                app.XEditField_2.Editable = 'on';
            else
                app.Resp = [];
            end
        end

        %% 初次读取数据更新界面
        function firstLoadData(app, newpathOut, flag, tag, DATA)
            % 检查数据是否正确
            if flag
                % 信息面板切换到文件面板
                app.TabGroup2.SelectedTab = app.Tab_5;

                app.newpath = newpathOut;

                % 显示数据长度
                app.Label_32.Text = app.Stime;
                % 显示窗口开始时间，默认为从头开始
                app.EditField.Value = '00: 00: 00';
                app.input_parameter.startTime = 0;

                if strcmp(app.dataTag, 'normal')
                    % 显示文件名称
                    app.fileLabel.Text = app.fileName;

                    % 显示窗口持续时间，默认为整段数据
                    app.EditField_2.Value = app.Stime;
                    app.input_parameter.windowTime = calcTime(app.Stime,'str2time');
                end

                if strcmp(tag, 'ECG')
                    % 保存原始心电
                    app.ECG_ori = DATA;

                    % 预处理数据
                    getIBIvararginMethod(app);
                    [Rtime, ECG, RtimeR, Rpeak, IBI, IBI_correction] = preProcess('ECG',app.ECG_ori, app.input_parameter.Fs,app.IBIvarargin);


                    % 更新ABC算法处理的数据集
                    updatePreData_ABC(app, Rtime, ECG, RtimeR, Rpeak, IBI, IBI_correction);

                    % 作图ECG + RR(ABC)
                    plotUIAxesABCandRR(app, 'A+B');
                elseif strcmp(tag, 'PPG')
                    % 保存原始血氧
                    app.PPG_ori = DATA;

                    % 预处理数据
                    getIBIvararginMethod(app);
                    [Rtime, PPG, RtimeR, Rpeak, IBI, IBI_correction] = preProcess('PPG',app.PPG_ori, app.input_parameter.Fs,app.IBIvarargin);


                    % 更新ABC算法处理的数据集
                    updatePreData_ABC(app, Rtime, PPG, RtimeR, Rpeak, IBI, IBI_correction);

                    % 作图ECG + RR(ABC)
                    plotUIAxesABCandRR(app, 'A+B');

                end

                % 数据校正重置为自动校正
                app.DropDown.Value = '自动校正';
                % 关闭无校正与自动校正切换功能
                app.DropDown.Enable = 'off';

                % 重置初次HRV分析为是
                app.ifFirstHRV = true;

                % 复选框算法ABC开启并重置为选中
                app.ACheckBox.Enable = 'on';
                app.ACheckBox.Value = 1;
                app.BCheckBox.Enable = 'on';
                app.BCheckBox.Value = 1;
%                 app.CCheckBox.Enable = 'on';
%                 app.CCheckBox.Value = 1;

                % 开启RR间隔文件读取功能，并将RR复选框变为未选中
                app.RRCheckBox.Enable = 'on';
                app.RRCheckBox.Value = 0;

                % 开启反转心电信号功能
                app.RevButton.Enable = 'on';

                % 开启坐标轴1,2显示范围修改功能
                app.XEditField.Editable = 'on';

                % 开启分析窗口编辑功能
                app.EditField.Editable = 'on';
                app.EditField_2.Editable = 'on';
                app.EditField_16.Editable = 'on';

                % 关闭编辑校正功能
                app.Button_3.Enable = 'off';
                app.Button_add.Enable = "off";
                app.Button_delete.Enable = "off";
                app.Spinner.Enable = "off";


                % 关闭保存批量HRV文件功能
                app.PushTool6.Enable = 'off';
                % 关闭保存批量RR文件功能
                app.PushTool10.Enable = 'off';

                % 关闭一屏分析功能
                app.AnaCheckBox.Enable = 'off';
                app.AnaCheckBox.Value = 0;

                % 重置R峰个数
                app.REditField.Value = '';
                % 重置R峰增减量
                app.REditField_2.Value = '';
            end
        end

        %% 初次读取文件夹(心电)更新首数据界面
        function firstLoadFolder(app, newpathOut, flag, ECG)
            % 检查数据是否正确
            if flag
                % 信息面板切换到文件面板
                app.TabGroup2.SelectedTab = app.Tab_6;

                app.newpath = newpathOut;
                % 显示文件夹名称
                folderName = strsplit(app.nameList{1}, '\');
                app.folderLabel.Text = cell2mat(folderName(end-1));
                % 显示首文件名称
                app.fileLabel_2.Text = app.fileName;
                % 显示首文件数据长度
                app.hhmmssLabel_2.Text = app.Stime;
                % 显示文件个数
                app.Label_14.Text = num2str(length(app.nameList));
                % 显示窗口开始时间，默认为从头开始
                app.EditField_14.Value = '00: 00: 00';
                app.input_parameter.startTime = 0;
                % 显示窗口持续时间，默认为整段数据
                app.EditField_15.Value = app.Stime;
                app.input_parameter.windowTime = calcTime(app.Stime,'str2time');
                
                % 保存原始心电
                app.ECG_ori = ECG;
                % 预处理数据
                getIBIvararginMethod(app);
                [Rtime, ECG, RtimeR, Rpeak, IBI, IBI_correction] = preProcess(ECG, app.input_parameter.Fs, app.IBIvarargin);
                
                % 更新ABC算法处理的数据集
                updatePreData_ABC(app, Rtime, ECG, RtimeR, Rpeak, IBI, IBI_correction); 

                % 作图ECG + RR(ABC)
                plotUIAxesABCandRR(app, 'A+B');

                % 关闭无校正与自动校正切换功能
                app.DropDown.Enable = 'off';

                % 重置初次HRV分析为是
                app.ifFirstHRV = true;

                % 复选框算法ABC开启并重置为选中
                app.ACheckBox.Enable = 'on';
                app.ACheckBox.Value = 1;
                app.BCheckBox.Enable = 'on';
                app.BCheckBox.Value = 1;
%                 app.CCheckBox.Enable = 'on';
%                 app.CCheckBox.Value = 1;

                % 开启反转心电信号功能
                app.RevButton.Enable = 'on';

                % 开启坐标轴1,2显示范围修改功能
                app.XEditField.Editable = 'on';

                % 开启分析窗口编辑功能
                app.EditField_14.Editable = 'on';
                app.EditField_15.Editable = 'on';

                % 开启保存批量HRV文件功能
                app.PushTool6.Enable = 'on';
                % 开启保存批量RR文件功能
                app.PushTool10.Enable = 'on';

                % 关闭编辑校正功能
                app.Button_3.Enable = 'off';
                app.Button_add.Enable = "off";
                app.Button_delete.Enable = "off";
                app.Spinner.Enable = "off";

                % 关闭保存睡眠心电HRV分析结果功能
                if strcmp(app.dataTag, 'normal')
                    app.PushTool9.Enable = 'off';
                end

                % 关闭RR读取功能
                app.RRCheckBox.Enable = 'off';

                % 重置R峰个数
                app.REditField.Value = '';
                % 重置R峰增减量
                app.REditField_2.Value = '';
            end
        end

        %% 更新ABC算法得到的数据
        function updatePreData_ABC(app, Rtime, ECG, RtimeR, Rpeak, IBI, IBI_correction)
            app.data_A.Rtime = Rtime;
            app.data_A.ECG = ECG;
            app.data_A.RtimeR = RtimeR.autoA;
            app.data_A.Rpeak = Rpeak.autoA;
            app.data_A.IBI = IBI.A;
            app.data_A.IBI_correction = IBI_correction.A; %A算法校正后数据
            app.data_A.IBI_autocorrection = IBI_correction.autoA; %%A算法校正基础上叠加自动校正算法

            app.data_B.Rtime = Rtime;
            app.data_B.ECG = ECG;
            app.data_B.RtimeR = RtimeR.autoB;
            app.data_B.Rpeak = Rpeak.autoB;
            app.data_B.IBI = IBI.B;
            app.data_B.IBI_correction = IBI_correction.B;
            app.data_B.IBI_autocorrection = IBI_correction.autoB;


%             app.data_C.Rtime = Rtime;
%             app.data_C.ECG = ECG;
%             app.data_C.RtimeR = RtimeR.C;
%             app.data_C.Rpeak = Rpeak.C;
%             app.data_C.IBI = IBI.C;
%             app.data_C.IBI_correction = IBI_correction.C;
        end

        %% 更新呼吸数据（截取）
        function updataResp(app,Resp)
            app.updata_Resp.res = RespSample(Resp, app.input_parameter.Fs, app.input_parameter.startTime, app.input_parameter.windowTime);
            [app.updata_Resp.bpm , app.updata_Resp.Fs] = calcResp_bpmANDFs(app.updata_Resp.res, app.input_parameter.Fs);
        end
        %% ABC算法处理及输入RR结果作图
        function plotUIAxesABCandRR(app, tag)
            Rtime = app.data_A.Rtime;
            ECG = app.data_A.ECG;
            RtimeR.A = app.data_A.RtimeR;
            Rpeak.A = app.data_A.Rpeak; %绘制的是校正后的IBI对应的R峰位置
            IBI.A = app.data_A.IBI_correction;
            IBI.autoA = app.data_A.IBI_autocorrection; %算法A的IBI自动校正结果
            IBI.A(:,1) = IBI.A(:,1)/60; % RR间隔横坐标变为分钟
            RtimeR.B = app.data_B.RtimeR;
            Rpeak.B = app.data_B.Rpeak;
            IBI.B = app.data_B.IBI_correction; 
            IBI.B(:,1) = IBI.B(:,1)/60; % RR间隔横坐标变为分钟
            IBI.autoB = app.data_B.IBI_autocorrection; %算法B的IBI自动校正结果

            % 分析范围左右界限
            rl = app.input_parameter.startTime/60;
            rr = (app.input_parameter.startTime+app.input_parameter.windowTime)/60;

            if contains(tag, 'RR')
                RtimeR.RR = app.data_RR.RtimeR;
                Rpeak.RR = app.data_RR.Rpeak;
                IBI.RR = app.data_RR.IBI;
                IBI.RR(:,1) = IBI.RR(:,1)/60; % RR间隔横坐标变为分钟
            end
            
            % 作图ECG+Rpeak
            % ECG图
            plot(app.UIAxes, Rtime, ECG)
            
            % R峰检测结果图
            hold(app.UIAxes, 'on')

            % 截取片段RR范围显示
            IBImin = min([min(IBI.A(:,2)), min(IBI.B(:,2))]);
            IBImax = max([max(IBI.A(:,2)), max(IBI.B(:,2))]);
%             IBImin = min([min(IBI.A(:,2)), min(IBI.B(:,2)), min(IBI.C(:,2))]);


            switch tag
                case 'A'
                    plot(app.UIAxes, RtimeR.A, Rpeak.A, 'r+')
                    legend(app.UIAxes, 'ECG', 'A','Location','SouthEast','Box','off')
                    % 作图RR间期
                    plot(app.UIAxes_2,[rl rl],[IBImin IBImax],'m',[rr rr],[IBImin IBImax],'m',  IBI.A(:,1), IBI.A(:,2), 'Color',[0.5 0.54 0.53],'LineWidth',2);
                    hold(app.UIAxes_2, 'on')
                    plot(app.UIAxes_2, IBI.autoA(:, 1)/60, IBI.autoA(:, 2),'r');
                    hold(app.UIAxes_2, 'off')
                    legend(app.UIAxes_2, 'rangeL', 'rangeR', 'A','Orientation','horizon','Box','off')
                case 'B'
                    plot(app.UIAxes, RtimeR.B, Rpeak.B, 'go')
                    legend(app.UIAxes, 'ECG', 'B','Location','SouthEast','Box','off')
                    % 作图RR间期
                    plot(app.UIAxes_2,[rl rl],[IBImin IBImax],'m',[rr rr],[IBImin IBImax],'m',  IBI.B(:,1), IBI.B(:,2), 'Color',[0.5 0.54 0.53],'LineWidth',2);
                    hold(app.UIAxes_2, 'on')
                    plot(app.UIAxes_2,IBI.autoB(:, 1)/60, IBI.autoB(:, 2),'g');hold(app.UIAxes_2, 'off')
                    legend(app.UIAxes_2, 'rangeL', 'rangeR', 'B','Orientation','horizon','Box','off')
                case 'C'
                    plot(app.UIAxes, RtimeR.C, Rpeak.C, 'bs')
                    legend(app.UIAxes, 'ECG', 'C','Location','SouthEast','Box','off')
                    % 作图RR间期
                    plot(app.UIAxes_2,[rl rl],[IBImin IBImax],'m',[rr rr],[IBImin IBImax],'m',  IBI.C(:,1), IBI.C(:,2), 'b');
                    legend(app.UIAxes_2, 'rangeL', 'rangeR', 'C','Orientation','horizon','Box','off')
                case 'RR'
                    plot(app.UIAxes, RtimeR.RR, Rpeak.RR, 'cx')
                    legend(app.UIAxes, 'ECG', 'RR','Location','SouthEast','Box','off')
                    % 作图RR间期
                    if ~isempty(app.data_RR.IBI)
                        plot(app.UIAxes_2,[rl rl],[IBImin IBImax],'m',[rr rr],[IBImin IBImax],'m',  app.data_RR.IBI(:,1), app.data_RR.IBI(:,2), 'Color',[0.5 0.54 0.53],'LineWidth',2);
                    end
                    plot(app.UIAxes_2,[rl rl],[IBImin IBImax],'m',[rr rr],[IBImin IBImax],'m',  IBI.RR(:,1), IBI.RR(:,2), 'c');
                    legend(app.UIAxes_2, 'rangeL', 'rangeR', 'RR','Orientation','horizon','Box','off')
                case 'A+B'
                    plot(app.UIAxes, RtimeR.A, Rpeak.A, 'r+', RtimeR.B, Rpeak.B, 'go')
                    legend(app.UIAxes, 'ECG', 'A', 'B','Location','SouthEast','Box','off')
                    % 作图RR间期
                    plot(app.UIAxes_2,[rl rl],[IBImin IBImax],'m',[rr rr],[IBImin IBImax],'m',  IBI.A(:,1), IBI.A(:,2), 'r', IBI.B(:,1), IBI.B(:,2), 'g');
                    legend(app.UIAxes_2, 'rangeL', 'rangeR', 'A', 'B','Orientation','horizon','Box','off')
                case 'A+C'
                    plot(app.UIAxes, RtimeR.A, Rpeak.A, 'r+', RtimeR.C, Rpeak.C, 'bs')
                    legend(app.UIAxes, 'ECG', 'A', 'C','Location','SouthEast','Box','off')
                    % 作图RR间期
                    plot(app.UIAxes_2,[rl rl],[IBImin IBImax],'m',[rr rr],[IBImin IBImax],'m',  IBI.A(:,1), IBI.A(:,2), 'r', IBI.C(:,1), IBI.C(:,2), 'b');
                    legend(app.UIAxes_2, 'rangeL', 'rangeR', 'A', 'C','Orientation','horizon','Box','off')
                case 'A+RR'
                    plot(app.UIAxes, RtimeR.A, Rpeak.A, 'r+', RtimeR.RR, Rpeak.RR, 'cx')
                    legend(app.UIAxes, 'ECG', 'A', 'RR','Location','SouthEast','Box','off')
                    % 作图RR间期
                    plot(app.UIAxes_2,[rl rl],[IBImin IBImax],'m',[rr rr],[IBImin IBImax],'m',  IBI.A(:,1), IBI.A(:,2), 'r', IBI.RR(:,1), IBI.RR(:,2), 'c');
                    legend(app.UIAxes_2, 'rangeL', 'rangeR', 'A', 'RR','Orientation','horizon','Box','off')
                case 'B+C'
                    plot(app.UIAxes, RtimeR.B, Rpeak.B, 'go', RtimeR.C, Rpeak.C, 'bs')
                    legend(app.UIAxes, 'ECG', 'B', 'C','Location','SouthEast','Box','off')
                    % 作图RR间期
                    plot(app.UIAxes_2,[rl rl],[IBImin IBImax],'m',[rr rr],[IBImin IBImax],'m',  IBI.B(:,1), IBI.B(:,2), 'g', IBI.C(:,1), IBI.C(:,2), 'b');
                    legend(app.UIAxes_2, 'rangeL', 'rangeR', 'B', 'C','Orientation','horizon','Box','off')
                case 'B+RR'
                    plot(app.UIAxes, RtimeR.B, Rpeak.B, 'go', RtimeR.RR, Rpeak.RR, 'cx')
                    legend(app.UIAxes, 'ECG', 'B', 'RR','Location','SouthEast','Box','off')
                    % 作图RR间期
                    plot(app.UIAxes_2,[rl rl],[IBImin IBImax],'m',[rr rr],[IBImin IBImax],'m',  IBI.B(:,1), IBI.B(:,2), 'g', IBI.RR(:,1), IBI.RR(:,2), 'c');
                    legend(app.UIAxes_2, 'rangeL', 'rangeR', 'B', 'RR','Orientation','horizon','Box','off')
                case 'C+RR'
                    plot(app.UIAxes, RtimeR.C, Rpeak.C, 'bs', RtimeR.RR, Rpeak.RR, 'cx')
                    legend(app.UIAxes, 'ECG', 'C', 'RR','Location','SouthEast','Box','off')
                    % 作图RR间期
                    plot(app.UIAxes_2,[rl rl],[IBImin IBImax],'m',[rr rr],[IBImin IBImax],'m',  IBI.C(:,1), IBI.C(:,2), 'b', IBI.RR(:,1), IBI.RR(:,2), 'c');
                    legend(app.UIAxes_2, 'rangeL', 'rangeR', 'C', 'RR','Orientation','horizon','Box','off')
                case 'A+B+C'
                    plot(app.UIAxes, RtimeR.A, Rpeak.A, 'r+', RtimeR.B, Rpeak.B, 'go', RtimeR.C, Rpeak.C, 'bs')
                    legend(app.UIAxes, 'ECG', 'A', 'B', 'C','Location','SouthEast','Box','off')
                    % 作图RR间期
                    plot(app.UIAxes_2,[rl rl],[IBImin IBImax],'m',[rr rr],[IBImin IBImax],'m',  IBI.A(:,1), IBI.A(:,2), 'r', IBI.B(:,1), IBI.B(:,2), 'g', IBI.C(:,1), IBI.C(:,2), 'b');
                    legend(app.UIAxes_2, 'rangeL', 'rangeR', 'A', 'B', 'C','Orientation','horizon','Box','off')
                case 'A+B+RR'
                    plot(app.UIAxes, RtimeR.A, Rpeak.A, 'r+', RtimeR.B, Rpeak.B, 'go', RtimeR.RR, Rpeak.RR, 'cx')
                    legend(app.UIAxes, 'ECG', 'A', 'B', 'RR','Location','SouthEast','Box','off')
                    % 作图RR间期
                    plot(app.UIAxes_2,[rl rl],[IBImin IBImax],'m',[rr rr],[IBImin IBImax],'m',  IBI.A(:,1), IBI.A(:,2), 'r', IBI.B(:,1), IBI.B(:,2), 'g', IBI.RR(:,1), IBI.RR(:,2), 'c');
                    legend(app.UIAxes_2, 'rangeL', 'rangeR', 'A', 'B', 'RR','Orientation','horizon','Box','off')
                case 'A+C+RR'
                    plot(app.UIAxes, RtimeR.A, Rpeak.A, 'r+', RtimeR.C, Rpeak.C, 'bs', RtimeR.RR, Rpeak.RR, 'cx')
                    legend(app.UIAxes, 'ECG', 'A', 'C', 'RR','Location','SouthEast','Box','off')
                    % 作图RR间期
                    plot(app.UIAxes_2,[rl rl],[IBImin IBImax],'m',[rr rr],[IBImin IBImax],'m',  IBI.A(:,1), IBI.A(:,2), 'r', IBI.C(:,1), IBI.C(:,2), 'b', IBI.RR(:,1), IBI.RR(:,2), 'c');
                    legend(app.UIAxes_2, 'rangeL', 'rangeR', 'A', 'C', 'RR','Orientation','horizon','Box','off')
                case 'B+C+RR'
                    plot(app.UIAxes, RtimeR.B, Rpeak.B, 'go', RtimeR.C, Rpeak.C, 'bs', RtimeR.RR, Rpeak.RR, 'cx')
                    legend(app.UIAxes, 'ECG', 'B', 'C', 'RR','Location','SouthEast','Box','off')
                    % 作图RR间期
                    plot(app.UIAxes_2,[rl rl],[IBImin IBImax],'m',[rr rr],[IBImin IBImax],'m',  IBI.B(:,1), IBI.B(:,2), 'g', IBI.C(:,1), IBI.C(:,2), 'b', IBI.RR(:,1), IBI.RR(:,2), 'c');
                    legend(app.UIAxes_2, 'rangeL', 'rangeR', 'B', 'C', 'RR','Orientation','horizon','Box','off')
                case 'A+B+C+RR'
                    plot(app.UIAxes, RtimeR.A, Rpeak.A, 'r+', RtimeR.B, Rpeak.B, 'go', RtimeR.C, Rpeak.C, 'bs', RtimeR.RR, Rpeak.RR, 'cx')
                    legend(app.UIAxes, 'ECG', 'A', 'B', 'C', 'RR','Location','SouthEast','Box','off')
                    % 作图RR间期
                    plot(app.UIAxes_2,[rl rl],[IBImin IBImax],'m',[rr rr],[IBImin IBImax],'m', IBI.A(:,1), IBI.A(:,2), 'r', IBI.B(:,1), IBI.B(:,2), 'g', IBI.C(:,1), IBI.C(:,2), 'b', IBI.RR(:,1), IBI.RR(:,2), 'c');
                    legend(app.UIAxes_2, 'rangeL', 'rangeR', 'A', 'B', 'C', 'RR','Orientation','horizon','Box','off')
                case 'none'
                    legend(app.UIAxes, 'ECG','Location','SouthEast','Box','off')
                    % 作图RR间期
                    plot(app.UIAxes_2, [rl rl],[IBImin IBImax],'m',[rr rr],[IBImin IBImax],'m');
                    legend(app.UIAxes_2, 'rangeL', 'rangeR', 'none','Orientation','horizon','Box','off')
            end
            
            hold(app.UIAxes, 'off')

            % 坐标轴1显示范围，横坐标默认为10秒
            app.UIAxes.XLim = [0 app.input_parameter.axes1Range];
            % 滑动条范围
            app.axes1XLim = Rtime(end);
            %app.Slider.Value = 0;
            app.Slider.Limits = [0 app.axes1XLim];

            if isempty(RtimeR.A)
                app.axes2XLim = RtimeR.B(end)/60;
            elseif isempty(RtimeR.B)
                app.axes2XLim = RtimeR.A(end)/60;
            else
                app.axes2XLim = max([RtimeR.A(end), RtimeR.B(end)])/60;
            end
%             app.axes2XLim = max([RtimeR.A(end), RtimeR.B(end), RtimeR.C(end)]);
            app.UIAxes_2.XLim = [0 app.axes2XLim];
        end

        %% 绘制SQI折线图
        function plotSQI(app)
            SQI_t = [5 : 9: length(app.SQI)*9-4]; 
            y = ones(length(SQI_t), 1);
            plot(app.UIAxes_SQI, SQI_t, app.SQI(:, 1), 'Color', [0.3010 0.7450 0.9330], 'LineWidth', 2);
            hold(app.UIAxes_SQI,'on')
            app.UIAxes_SQI.YLim = [0.5 1];
            if any(app.SQI(:, 2)) || any(app.SQI(:, 3))
                IBIerror = find(app.SQI(:, 2)==0);
                HRerror = find(app.SQI(:, 3)==0);
                if ~isempty(IBIerror) || ~isempty(HRerror)
                    for n = 1:length(IBIerror)-1
                        area(app.UIAxes_SQI, SQI_t(IBIerror(n): IBIerror(n)+1), y(IBIerror(n): IBIerror(n)+1), 'FaceColor',[0.75, 0.75, 0.75], 'FaceAlpha', .5, 'EdgeAlpha', .5)
                    end
                    for m = 1:length(HRerror)-1
                        area(app.UIAxes_SQI, SQI_t(HRerror(m): HRerror(m)+1), y(HRerror(m): HRerror(m)+1), 'FaceColor',[0.98, 0.92, 0.84],'FaceAlpha', .5,'EdgeAlpha', .5)
                    end
                end
            end
            hold(app.UIAxes_SQI,'off')
        end
        %% 返回算法框选中的算法
        function results = chooseAlgorithm(app)
            value(1) = app.ACheckBox.Value;
            value(2) = app.BCheckBox.Value;
            value(3) = app.CCheckBox.Value;
            value(4) = app.RRCheckBox.Value;
            if value(1)==1 && value(2)==0 && value(3)==0 && value(4)==0
                results = 'A';
            elseif value(1)==0 && value(2)==1 && value(3)==0 && value(4)==0
                results = 'B';
            elseif value(1)==0 && value(2)==0 && value(3)==1 && value(4)==0
                results = 'C';
            elseif value(1)==0 && value(2)==0 && value(3)==0 && value(4)==1
                results = 'RR';
            elseif value(1)==1 && value(2)==1 && value(3)==0 && value(4)==0
                results = 'A+B';
            elseif value(1)==1 && value(2)==0 && value(3)==1 && value(4)==0
                results = 'A+C';
            elseif value(1)==1 && value(2)==0 && value(3)==0 && value(4)==1
                results = 'A+RR';
            elseif value(1)==0 && value(2)==1 && value(3)==1 && value(4)==0
                results = 'B+C';
            elseif value(1)==0 && value(2)==1 && value(3)==0 && value(4)==1
                results = 'B+RR';
            elseif value(1)==0 && value(2)==0 && value(3)==1 && value(4)==1
                results = 'C+RR';
            elseif value(1)==1 && value(2)==1 && value(3)==1 && value(4)==0
                results = 'A+B+C';
            elseif value(1)==1 && value(2)==1 && value(3)==0 && value(4)==1
                results = 'A+B+RR';
            elseif value(1)==1 && value(2)==0 && value(3)==1 && value(4)==1
                results = 'A+C+RR';
            elseif value(1)==0 && value(2)==1 && value(3)==1 && value(4)==1
                results = 'B+C+RR';
            elseif value(1)==1 && value(2)==1 && value(3)==1 && value(4)==1
                results = 'A+B+C+RR';
            elseif value(1)==0 && value(2)==0 && value(3)==0 && value(4)==0
                results = 'none';
            end
        end
        
        %% 返回算法框选中的唯一算法
        function results = chooseOnlyAlgorithm(app)
            value(1) = app.ACheckBox.Value;
            value(2) = app.BCheckBox.Value;
            value(3) = app.CCheckBox.Value;
            value(4) = app.RRCheckBox.Value;
            results = [];
            if length(find(value==1)) == 1
                idx = find(value==1);
                switch idx
                    case 1
                        results = 'A';
                    case 2
                        results = 'B';
                    case 3
                        results = 'C';
                    case 4
                        results = 'RR';
                end
            end
        end

        %% 关闭选择其他算法功能
        function closeOtherAlgorithm(app)
            method = chooseOnlyAlgorithm(app);
            
            switch method
                case 'A'
                    app.BCheckBox.Enable = 'off';
                    app.CCheckBox.Enable = 'off';
                    app.RRCheckBox.Enable = 'off';
                case 'B'
                    app.ACheckBox.Enable = 'off';
                    app.CCheckBox.Enable = 'off';
                    app.RRCheckBox.Enable = 'off';
                case 'C'
                    app.ACheckBox.Enable = 'off';
                    app.BCheckBox.Enable = 'off';
                    app.RRCheckBox.Enable = 'off';
                case 'RR'
                    app.ACheckBox.Enable = 'off';
                    app.BCheckBox.Enable = 'off';
                    app.CCheckBox.Enable = 'off';
            end
        end

        %% 返回算法框选中的唯一算法
        function results = chooseTFmethod(app,tab)
            results = [];
            %选择时频分析面板1，2D_Spectrogram图
            if  strcmp(tab, 's')
                value(1) = app.BurgButton_S.Value;
                value(2) = app.LombScargleButton_S.Value;
                value(3) = app.WaveletButton_S.Value;
            
            if length(find(value==1)) == 1
                idx = find(value==1);
                switch idx
                    case 1
                        results = 'ar'; %Burg
                    case 2
                        results = 'lomb'; 
                    case 3
                        results = 'wavelet';
                end
            end
            %选择时频分析面板2
            elseif strcmp(tab, 'w')
                value(1) = app.BurgButton_W.Value;
                value(2) = app.LombScargleButton_W.Value;
                value(3) = app.WaveletButton_W.Value;
            if length(find(value==1)) == 1
                idx = find(value==1);
                switch idx
                    case 1
                        results = 'burg'; 
                    case 2
                        results = 'lomb'; 
                    case 3
                        results = 'wavelet';
                end
            end
            end
        end

        %% 选择时频分析显示图形
        function ViewingStyle = chooseTFPlotView(app)
            value(1) = app.SurfaceButton.Value;
            value(2) = app.WaterfallButton.Value;
            value(3) = app.LFHFRatioButton.Value;
            value(4) = app.LFHFPowerButton.Value;
            if length(find(value==1)) == 1
                idx = find(value==1);
                switch idx
                    case 1
                        ViewingStyle = 'surface';
                    case 2
                        ViewingStyle = 'waterfall';
                    case 3
                        ViewingStyle = 'LFHFRt';
                    case 4
                        ViewingStyle = 'LFHFPw';
                end
            end

        end
        %% 更新预处理数据集
        function updatePreData(app, tag, Rtime, ECG, RtimeR, Rpeak, IBI)
            if strcmp(tag, 'ori')
                app.data_ori.Rtime = Rtime;
                app.data_ori.ECG = ECG;
                app.data_ori.RtimeR = IBI(:,1);
                app.data_ori.Rpeak = ECG(round(IBI(:,1)*app.input_parameter.Fs));
                app.data_ori.IBI = IBI;
            elseif strcmp(tag, 'correction')
                app.data_correction.Rtime = Rtime;
                app.data_correction.ECG = ECG;
                app.data_correction.RtimeR = RtimeR;
                app.data_correction.Rpeak = Rpeak;
                app.data_correction.IBI = IBI;
            elseif strcmp(tag, 'update')
                app.data_update.fileName = app.fileName;
                app.data_update.Stime = app.Stime;
                app.data_update.Rtime = Rtime;
                app.data_update.ECG = ECG;
                app.data_update.RtimeR = RtimeR;
                app.data_update.Rpeak = Rpeak;
                app.data_update.IBI = IBI;
            elseif strcmp(tag, 'RR')
                app.data_RR.Rtime = Rtime;
                app.data_RR.ECG = ECG;
                app.data_RR.RtimeR = RtimeR;
                app.data_RR.Rpeak = Rpeak;
                app.data_RR.IBI = IBI;
            end
        end

        %% 更新R峰数据
        function updateRpeakData(app, tag)
            % 更新R峰个数与增减量
            if strcmp(tag, 'none->manual')
                noneCounts = length(app.data_ori.IBI);
                manualCounts = length(app.data_update.IBI);
                app.REditField.Value = ['(无)' num2str(noneCounts) '/' '(手)' num2str(manualCounts)];
                
                addCounts = length(setdiff(app.data_update.IBI(:,1), app.data_ori.IBI(:,1)));
                deleteCounts = length(setdiff(app.data_ori.IBI(:,1), app.data_update.IBI(:,1)));
                app.REditField_2.Value = ['+' num2str(addCounts) '/' '-' num2str(deleteCounts)];
            elseif strcmp(tag, 'auto->manual')
                autoCounts = length(app.data_correction.IBI);
                manualCounts = length(app.data_update.IBI);
                app.REditField.Value = ['(自)' num2str(autoCounts) '/' '(手)' num2str(manualCounts)];
                
                addCounts = length(setdiff(app.data_update.IBI(:,1), app.data_correction.IBI(:,1)));
                deleteCounts = length(setdiff(app.data_correction.IBI(:,1), app.data_update.IBI(:,1)));
                app.REditField_2.Value = ['+' num2str(addCounts) '/' '-' num2str(deleteCounts)];
            end
        end

        %% 更新HRV数据集
        function updateHRVData(app, tag, TDoutput, FDoutput, PDoutput, NDoutput, TFoutput)
            if strcmp(tag, 'ori')
                app.data_ori.TDoutput = TDoutput;
                app.data_ori.FDoutput = FDoutput;
                app.data_ori.PDoutput = PDoutput;
                app.data_ori.NDoutput = NDoutput;
                app.data_ori.TFoutput = TFoutput;

            elseif strcmp(tag, 'correction')
                app.data_correction.TDoutput = TDoutput;
                app.data_correction.FDoutput = FDoutput;
                app.data_correction.PDoutput = PDoutput;
                app.data_correction.NDoutput = NDoutput;
                app.data_correction.TFoutput = TFoutput;

            elseif strcmp(tag, 'update')
                app.data_update.TDoutput = TDoutput;
                app.data_update.FDoutput = FDoutput;
                app.data_update.PDoutput = PDoutput;
                app.data_update.NDoutput = NDoutput;
                app.data_update.TFoutput = TFoutput;

                app.data_update.Fs = app.input_parameter.Fs;
            end

        end
        
        %% 作图ECG + RR
        function plotUIAxes(app, dataInfo)
            ECG = dataInfo.ECG;
            Rtime = dataInfo.Rtime;
            RtimeR = dataInfo.RtimeR;
            Rpeak = dataInfo.Rpeak;
            IBI = dataInfo.IBI;
            IBI(:,1) = IBI(:,1)/60; % RR间隔横坐标变为分钟
            HR = 60./IBI(:,2); %RR转换为HR
            % 分析范围左右界限
            rl = app.input_parameter.startTime/60;
            rr = (app.input_parameter.startTime+app.input_parameter.windowTime)/60;

            % 作图ECG+Rpeak
            % ECG图
            plot(app.UIAxes, Rtime, ECG)
            % R峰检测结果图
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, RtimeR, Rpeak, 'r+');
            legend(app.UIAxes, 'ECG', 'Rpeak','Location','SouthEast','Box','off')
            hold(app.UIAxes, 'off')
            % 坐标轴1显示范围，横坐标默认为10秒
            app.UIAxes.XLim = [0 app.input_parameter.axes1Range]; 
            % 滑动条范围
            app.axes1XLim = Rtime(end);
            %app.Slider.Value = 0;
            app.Slider.Limits = [0 app.axes1XLim];
            
            % 截取片段RR范围显示
            IBImin = min(IBI(:,2));
            IBImax = max(IBI(:,2));
            HRmin = min(HR);
            HRmax = max(HR);          

            if app.Button_RR.Value == 1
            % 作图RR间期
                plot(app.UIAxes_2,[rl rl],[IBImin IBImax],'m',[rr rr],[IBImin IBImax],'m', IBI(:,1), IBI(:,2),'b');
                legend(app.UIAxes_2, 'rangeL', 'rangeR', 'RR','Orientation','horizon','Box','off')
            else
                plot(app.UIAxes_2,[rl rl],[HRmin HRmax],'m',[rr rr],[HRmin HRmax],'m', IBI(:,1), HR,'b');
                legend(app.UIAxes_2, 'rangeL', 'rangeR', 'HR','Orientation','horizon','Box','off')
            end
            
            % 坐标轴2显示范围，横坐标默认为整段数据长度
            app.axes2XLim = RtimeR(end)/60;
            app.UIAxes_2.XLim = [0 app.axes2XLim];
        end
        
        %% 呼吸信号和心率作图
        function plotUIAxesRespAndHR(app, dataInfo, Resp)
            Rtime = dataInfo.Rtime;
            IBI = dataInfo.IBI;
            HR = 60./IBI(:,2);
      
            % 呼吸信号图
            plot(app.UIAxes_3, Rtime, Resp)
            % HR图
            plot(app.UIAxes_4, IBI(:,1), HR)

            % 坐标轴显示范围，横坐标默认为10秒
            app.UIAxes_3.XLim = [0 app.input_parameter.axes3Range];
            app.UIAxes_4.XLim = [0 app.input_parameter.axes3Range];
            % 滑动条范围
            app.axes3XLim = Rtime(end);
            app.Slider_2.Value = 0;
            app.Slider_2.Limits = [0 app.axes3XLim];
        end

        %% 更新图（鼠标点击时）
        function refreshECG_RRAxes(app)
            ECG = app.data_update.ECG;
            Rtime = app.data_update.Rtime;
            RtimeR = app.data_update.RtimeR;
            Rpeak = app.data_update.Rpeak;
            IBI = app.data_update.IBI;
            IBI(:,1) = IBI(:,1)/60; % RR间隔横坐标变为分钟
            HR = 60./IBI(:,2); %RR转换为HR
            % 分析范围左右界限
            rl = app.input_parameter.startTime/60;
            rr = (app.input_parameter.startTime+app.input_parameter.windowTime)/60;

            % 作图ECG+Rpeak
            % ECG图
            plot(app.UIAxes, Rtime, ECG)
            % R峰检测结果图
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, RtimeR, Rpeak, 'r+');
            legend(app.UIAxes, 'ECG', 'Rpeak','Location','SouthEast','Box','off')
            hold(app.UIAxes, 'off')

            % 截取片段RR范围显示
            IBImin = min(IBI(:,2));
            IBImax = max(IBI(:,2));
            HRmin = min(HR);
            HRmax = max(HR);    

            if app.Button_RR.Value == 1
            % 作图RR间期
            plot(app.UIAxes_2,[rl rl],[IBImin IBImax],'m',[rr rr],[IBImin IBImax],'m', IBI(:,1), IBI(:,2),'b');
            legend(app.UIAxes_2, 'rangeL', 'rangeR', 'RR','Orientation','horizon','Box','off')
            else
                plot(app.UIAxes_2,[rl rl],[HRmin HRmax],'m',[rr rr],[HRmin HRmax],'m', IBI(:,1), HR,'b');
                legend(app.UIAxes_2, 'rangeL', 'rangeR', 'HR','Orientation','horizon','Box','off')
            end
        end

        %% 更新HRV参数表格
        function refreshTable(app, flag)
            if strcmp(flag, 'none->manual')
                tableTD = tableTDProcess(app.data_ori.TDoutput, app.data_update.TDoutput, app.updata_Resp.bpm, app.updata_Resp.Fs);
                tableFD_FFT = tableFDProcess(app.data_ori.FDoutput, app.data_update.FDoutput, 'welch');
                tableFD_AR = tableFDProcess(app.data_ori.FDoutput, app.data_update.FDoutput, 'ar');
                tableNL = tableNLProcess(app.data_ori.PDoutput, app.data_ori.NDoutput,...
                    app.data_update.PDoutput, app.data_update.NDoutput);
            elseif strcmp(flag, 'auto->manual')
                tableTD = tableTDProcess(app.data_correction.TDoutput, app.data_update.TDoutput, app.updata_Resp.bpm, app.updata_Resp.Fs);
                tableFD_FFT = tableFDProcess(app.data_correction.FDoutput, app.data_update.FDoutput, 'welch');
                tableFD_AR = tableFDProcess(app.data_correction.FDoutput, app.data_update.FDoutput, 'ar');
                tableNL = tableNLProcess(app.data_correction.PDoutput, app.data_correction.NDoutput,...
                    app.data_update.PDoutput, app.data_update.NDoutput);
            end
            app.UITable.Data = tableTD;
            app.UITable.FontSize = 14;
%             s = uistyle('HorizontalAlignment','center');
%             addStyle(app.UITable,s,'table','');
            app.UITable_2.Data = tableFD_FFT;
            app.UITable_2.FontSize = 16;
%             addStyle(app.UITable_2,s,'table','');
            app.UITable_3.Data = tableFD_AR;
            app.UITable_3.FontSize = 16;
%             addStyle(app.UITable_3,s,'table','');
            app.UITable_4.Data = tableNL;
            app.UITable_4.FontSize = 16;
%             addStyle(app.UITable_4,s,'table','');
        end

        %% 时频分析绘图
        function plotUIAxesTF(app, datainfo, IBI)
                Methods = chooseTFmethod(app,'s');
                %面板1时频绘图
                plotSpectrogram(app.UIAxesTF_S,app.input_parameter.Fs,datainfo.TFoutput, app.input_parameter.ar.VLF, ...
                    app.input_parameter.ar.LF, app.input_parameter.ar.HF, [],[0 0.6], true,Methods, IBI)

                %面板2绘图
                Methods2 = chooseTFmethod(app,'w');
                ViewingStyle = chooseTFPlotView(app); %选择绘图样式
                if strcmp(Methods2, 'burg')
                    psd = datainfo.TFoutput.ar.psd;
                    f = datainfo.TFoutput.ar.f;
                    t = datainfo.TFoutput.ar.t;
                    lf = datainfo.TFoutput.ar.hrv.aLF;
                    hf = datainfo.TFoutput.ar.hrv.aHF;
                    t2 = linspace(t(1),t(end),100); %time values for interp.
                    if size(psd,2)>1
                        %interpolation插值
                        lfhf=interp1(t,datainfo.TFoutput.ar.hrv.LFHF,t2,'spline')'; 
                    end
                elseif strcmp(Methods2, 'lomb')
                    psd = datainfo.TFoutput.lomb.psd;
                    f = datainfo.TFoutput.lomb.f;
                    t = datainfo.TFoutput.lomb.t;
                    lf = datainfo.TFoutput.lomb.hrv.aLF;
                    hf = datainfo.TFoutput.lomb.hrv.aHF;
                    t2 = linspace(t(1),t(end),100); %time values for interp.
                if size(psd,2)>1
                %interpolation
                    lfhf=interp1(t,datainfo.TFoutput.lomb.hrv.LFHF,t2,'spline')';
                end
                else
                    psd=datainfo.TFoutput.wav.psd;
                    f=datainfo.TFoutput.wav.f;
                    t=datainfo.TFoutput.wav.t; t2=t;
                    lf=datainfo.TFoutput.wav.hrv.aLF;
                    hf=datainfo.TFoutput.wav.hrv.aHF;
                    lfhf=datainfo.TFoutput.wav.hrv.LFHF;
                end

                VLF = [app.input_parameter.welch.VLF(1), app.input_parameter.welch.VLF(2)];
                LF = [app.input_parameter.welch.LF(1), app.input_parameter.welch.LF(2)];
                HF = [app.input_parameter.welch.HF(1), app.input_parameter.welch.HF(2)];

                switch ViewingStyle
                    case 'surface'
                        plotWaterfall(app.UIAxesTF_W,t, f, psd, VLF, LF, HF, 'surf', strcmp(Methods2, 'wavelet') )
                        xlabel(app.UIAxesTF_W,'Time (s)');
                        ylabel(app.UIAxesTF_W,'Freq(Hz)');
                        zlabel(app.UIAxesTF_W,'PSD(s²/Hz)');
                    case 'waterfall'
                        plotWaterfall(app.UIAxesTF_W,t, f, psd, VLF, LF, HF, 'waterfall', strcmp(Methods2, 'wavelet') )
                        xlabel(app.UIAxesTF_W,'Time (s)');
                        ylabel(app.UIAxesTF_W,'Freq(Hz)');
                        zlabel(app.UIAxesTF_W,'PSD(s²/Hz)');
                    case 'LFHFRt'
                        above=((lfhf>1).*lfhf);
                        above(above==0)=1;
                        below=((lfhf<1).*lfhf);
                        below(below==0)=1;                 
                        area(app.UIAxesTF_W, t2,above,'basevalue',1,'facecolor','c')
                        hold(app.UIAxesTF_W,'on')
                        area(app.UIAxesTF_W, t2,below,'basevalue',1,'facecolor','m')
                        hold(app.UIAxesTF_W,'off')                                                
                        xlabel(app.UIAxesTF_W,'Time (s)');
                        ylabel(app.UIAxesTF_W,'LF/HF (ratio)');
                        
                    case 'LFHFPw'
                        plot(app.UIAxesTF_W,t, lf, 'r')
                        hold(app.UIAxesTF_W, 'on')
                        plot(app.UIAxesTF_W,t, hf, 'b')
                        hold(app.UIAxesTF_W, 'off')
                        xlabel(app.UIAxesTF_W,'Time (s)');
                        ylabel(app.UIAxesTF_W,'Power (ms^2)');
                        legend(app.UIAxesTF_W,{'LF','HF'})
                        xlim(app.UIAxesTF_W,[t(1) t(end)])
                        
                end
        end

        %% PNS+SNS绘图
        %自主神经张力分析
        function plotPNS_SNS(app)
            cla(app.UIAxesPNS); cla(app.UIAxesSNS);
            x = [-5:0.1:5]; y = normpdf(x, 0, 1)*0.5+0.02; 
            summermap = colormap(summer(length(x)));
            pinkmap = colormap(pink(length(x)));

            %计算PNS、SNS结果值
            [PNS, SNS] = calculatePNS_SNS(app.data_update.TDoutput, app.data_update.PDoutput);
            PNS = round(PNS, 2); 
            SNS = round(SNS, 2); 


            hold(app.UIAxesPNS, "on") 
            hold(app.UIAxesSNS, "on")

            for i = 1:40
                area(app.UIAxesPNS, x(i: i+1), y(i: i+1) ,'FaceColor',pinkmap(i+30, :),'FaceAlpha',0.7,'EdgeColor','none'); %PNS左边[-5,-1]
                area(app.UIAxesSNS, x(101-i: 102-i), y(101-i: 102-i) ,'FaceColor',pinkmap(i+30, :),'FaceAlpha',0.7,'EdgeColor','none'); %SNS左边[5,1]
            end

             for i = 1:60
                area(app.UIAxesPNS, x(40+i: i+41), y(40+i: i+41) ,'FaceColor',summermap(61-i, :),'FaceAlpha',0.6,'EdgeColor','none');  %PNS右边[-1,5]
                area(app.UIAxesSNS, x(i: i+1), y(i: i+1) ,'FaceColor',summermap(i, :),'FaceAlpha',0.6,'EdgeColor','none');  %SNS右边[1,-5] 1~61项
             end

             %绘制正态分布图
            plot(app.UIAxesPNS, x, y,'Color',[0.5 0.5 0.5]);
            plot(app.UIAxesSNS, x, y,'Color',[0.5 0.5 0.5]);
            %绘制结果线
            plot(app.UIAxesPNS, [PNS PNS], [0 0.25],'Color','k', 'LineWidth', 1.5); xlim([-5, 5])
            plot(app.UIAxesSNS, [SNS SNS], [0 0.25],'Color','k' , 'LineWidth', 1.5); xlim([-5, 5])

            title(app.UIAxesPNS,['副交感指数=',num2str(PNS)]); 
            title(app.UIAxesSNS,['交感指数=',num2str(SNS)]);

            %绘制一倍、两倍标准差界限
%             plot(app.UIAxesPNS, [0 0], [0 0.25],'Color',[0.5 0.5 0.5 0.6], 'LineWidth', 2); 
%             plot(app.UIAxesPNS, [1 1], [0 0.25],'Color',[0.5 0.5 0.5 0.6], 'LineWidth', 1); 
%             plot(app.UIAxesPNS, [-1 -1], [0 0.25],'Color',[0.5 0.5 0.5 0.6], 'LineWidth', 1); 
%             plot(app.UIAxesPNS, [2 2], [0 0.25],'Color',[0.5 0.5 0.5 0.6], 'LineWidth', 1, 'LineStyle','--'); 
%             plot(app.UIAxesPNS, [-2 -2], [0 0.25],'Color',[0.5 0.5 0.5 0.6], 'LineWidth', 1, 'LineStyle','--'); 
% 
%             plot(app.UIAxesSNS, [0 0], [0 0.25],'Color',[0.5 0.5 0.5 0.6], 'LineWidth', 2); 
%             plot(app.UIAxesSNS, [1 1], [0 0.25],'Color',[0.5 0.5 0.5 0.6], 'LineWidth', 1); 
%             plot(app.UIAxesSNS, [-1 -1], [0 0.25],'Color',[0.5 0.5 0.5 0.6], 'LineWidth', 1); 
%             plot(app.UIAxesSNS, [2 2], [0 0.25],'Color',[0.5 0.5 0.5 0.6], 'LineWidth', 1, 'LineStyle','--'); 
%             plot(app.UIAxesSNS, [-2 -2], [0 0.25],'Color',[0.5 0.5 0.5 0.6], 'LineWidth', 1, 'LineStyle','--'); 


            hold(app.UIAxesPNS, "off")
            hold(app.UIAxesSNS, "off")

        end
        %% 更新HRV绘图
        function refreshHRVAxes(app, datainfo)
            IBI_sample = IBISample(app.data_update.IBI, app.input_parameter.startTime, app.input_parameter.windowTime); % 截取IBI片段
            % 判断截取范围IBI是否为空
            if ~isempty(IBI_sample)
                RR = IBI_sample(:,2); % 提取RR

                % 时域绘图
                histogram(app.UIAxes2, RR, 30) % IBI直方图
                histogram(app.UIAxes2_2, datainfo.TDoutput.HR, 30) % HR直方图
        
                % 频域绘图
                % Welch
                plotPSD(app.UIAxes2_3, datainfo.FDoutput.welch.f,...
                    datainfo.FDoutput.welch.psd, app.input_parameter.welch.VLF,...
                    app.input_parameter.welch.LF, app.input_parameter.welch.HF, [0 0.6], [])
                % AR
                plotPSD(app.UIAxes2_6, datainfo.FDoutput.ar.f,...
                    datainfo.FDoutput.ar.psd, app.input_parameter.ar.VLF,...
                    app.input_parameter.ar.LF, app.input_parameter.ar.HF, [0 0.6], [])
                
                % 非线性绘图
                plotPoincare(app.UIAxes2_4, IBISample(datainfo.IBI, app.input_parameter.startTime, app.input_parameter.windowTime), datainfo.PDoutput)
                plotNL(app.UIAxes2_5, datainfo.NDoutput, app.input_parameter.breakpoint)
                
                %时域绘图
                plotUIAxesTF(app, datainfo, IBI_sample)

                plotPNS_SNS(app)
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, filepath, fileName, Fs, DATAlength, dataInput)
%             app.CallingWindow = mainapp;
%             app.fileLabel.Text = fileName;
%             app.fileName = fileName;
%             app.HzEditField.Value = Fs;
%             app.Label_32.Text = DATAlength;
%             app.Stime = DATAlength;
%             [~,n] = size(dataInput);
%             app.ECG_ori = dataInput(:, 1);
%             if n ==2
%                 app.Resp = dataInput(:, 2);
%             end
%             flag = true;
%             app.newpath = filepath;
%             % 获取界面输入参数
%             getFileParameter(app);
%             getHRVParameter(app);
%             
%             % 数据类别
%             app.dataTag = 'normal';
% 
%             % 加载数据
%             firstLoadData(app, app.newpath, flag, app.ECG_ori);
% 
%             % 加载呼吸数据
%             firstLoadResp(app, flag, app.Resp);
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            % 打开心电文件
            
            % 获取界面输入参数
            getFileParameter(app);
            getHRVParameter(app);
            
            % 导入数据
            [newpathOut, flag, app.fileName, app.suffix, Resp_ori, ECG, app.Stime] = importData(app.newpath, app.input_parameter.Fs, 'ECG');

            % 数据类别
            app.dataTag = 'normal';

            % 加载数据
            firstLoadData(app, newpathOut, flag, 'ECG',ECG);

            % 加载呼吸数据
            firstLoadResp(app, flag, Resp_ori);
        end

        % Callback function: PushTool11
        function PushTool11Clicked(app, event)
            % 打开血氧文件
            % 获取界面输入参数
            getFileParameter(app);
            getHRVParameter(app);
            
            % 导入数据
            [newpathOut, flag, app.fileName, app.suffix, Resp_ori, PPG, app.Stime] = importData(app.newpath, app.input_parameter.Fs, 'PPG');

            % 数据类别
            app.dataTag = 'normal';

            % 加载数据
            firstLoadData(app, newpathOut, flag, 'PPG',PPG);

            % 加载呼吸数据
            firstLoadResp(app, flag, Resp_ori);
        end

        % Button pushed function: RevButton
        function RevButtonPushed(app, event)
            % 反转心电信号方向
            if ~isempty(app.ECG_ori)
                % 信号取反getFileParameter
                app.ECG_ori = -app.ECG_ori;
                % 加载数据
                firstLoadData(app, app.newpath, true,'ECG', app.ECG_ori);
            elseif ~isempty(app.PPG_ori)
                % 信号取反
                app.PPG_ori = -app.PPG_ori;
                % 加载数据
                firstLoadData(app, app.newpath, true,'PPG', app.PPG_ori);
            end
        end

        % Callback function: PushTool3
        function PushTool3Clicked(app, event)
            % 保存HRV分析结果文件

            % 先判断是否进入编辑状态
            if strcmp(app.Button_3.Enable, 'on')
                exportHRV(app.input_parameter, app.newpath, app.fileName, app.data_update);
            else
                msgbox('请先进行HRV分析')
            end
        end

        % Callback function: PushexportPDF
        function PushexportPDFClicked(app, event)
            % 保存HRV分析结果PDF文件

            % 先判断是否进入编辑状态
            if strcmp(app.Button_3.Enable, 'on')
                f = figure('position',[100 -5 794 1163], 'Visible','off');
                %  f = figure('position',[100 20 794 1163]);
                set(f,'PaperUnits','centimeters'); 
                set(f,'PaperSize',[21 29.7]); 
                dataselect = [1,3,4]; nan = cell(1,3);

                %心电
                position1 = [0.05 0.842 0.88 0.1]; %[左 下 宽 高]
                subplot('Position',position1)
                plot(app.data_A.Rtime,app.data_A.ECG);xlim([0,20]);
                if ~isempty(app.ECG_ori)
                    [name, ECG] = title([app.fileName, ' 自主神经张力分析报告'],'ECG'); name.FontSize = 14; ECG.FontSize = 12;
                elseif ~isempty(app.PPG_ori)
                    [name, PPG] = title([app.fileName, ' 自主神经张力分析报告'],'PPG'); name.FontSize = 14; PPG.FontSize = 12;
                end
                
                %RR间隔图
                position2 = [0.05 0.69 0.88 0.1];
                subplot('Position',position2)
                plot(app.data_update.IBI(:,1)/60,app.data_update.IBI(:,2))
                title('RR interval', 'FontSize', 12);
                
                %时域、频域结果表格
                position3 = [30 400 332 270]; 
                hold on
                try
                    dataT = app.UITable.Data(:, dataselect);
                    dataF = [app.UITable_2.Data(:, dataselect) ;nan];
                    data = [dataT,dataF];
                catch
                    dataT = [app.UITable.Data(:, dataselect) ;nan];
                    dataF = app.UITable_2.Data(:, dataselect);
                    data = [dataT,dataF];
                end
                tableT = uitable(f,'Position',position3, 'Data', data);
                tableT.RowName={};tableT.ColumnName={'Variable', 'Corr', 'Unit','Variable', 'Corr', 'Unit'};
                tableT.ColumnWidth={'auto', 50, 40,'auto', 50, 40};

                %RR、HR直方图
                position4 = [0.5 0.54 0.2 0.12]; position5 = [0.73 0.54 0.2 0.12];
                IBI_sample = IBISample(app.data_update.IBI, app.input_parameter.startTime, app.input_parameter.windowTime); % 截取IBI片段
                % 判断截取范围IBI是否为空
                if ~isempty(IBI_sample)
                    RR = IBI_sample(:,2); % 提取RR
                    subplot('Position',position4)
                    histogram(RR, 30) % IBI直方图 
                    title('RR直方图');
%                     hold on
                    subplot('Position',position5)
                    histogram(app.data_update.TDoutput.HR, 30) % HR直方图
                    title('HR直方图');
                end

                %AR频谱图
                position7 = [0.5 0.385 0.43 0.12]; 
                subplot('Position', position7);
                plotPSD_aH(app.data_update.FDoutput.ar.f,...
                    app.data_update.FDoutput.ar.psd, app.input_parameter.ar.VLF,...
                    app.input_parameter.ar.LF, app.input_parameter.ar.HF)
                title('AR频谱图');

                %非线性结果
                position8 = [30 215 172 157];
                dataN = app.UITable_4.Data(:, dataselect);
                tableN = uitable(f, 'Position', position8,'Data', dataN);
                tableN.RowName={};tableN.ColumnName={'Variable', 'Corr', 'Unit'};
                tableN.ColumnWidth={'auto', 50, 45}; 

                position9 = [0.35 0.23 0.25 0.12]; 
                subplot('Position', position9);
                plotPoincare_aH(IBISample(app.data_update.IBI, app.input_parameter.startTime, app.input_parameter.windowTime), app.data_update.PDoutput)
                position10 = [0.68 0.23 0.25 0.12];
                subplot('Position', position10);
                plotNL_aH(app.data_update.NDoutput, app.input_parameter.breakpoint)

                %自主神经分析结果PNS+SNS
                subplot('Position', [0.03 0.03 0.24 0.13]);
                [PNS,SNS] = plotPNS(app.data_update);
                subplot('Position', [0.3 0.03 0.24 0.13]);
                plotSNS(app.data_update);
%                 annotation(f,'textbox',[0.05 0.15 0.1 0.1],'EdgeColor','none', 'String','SD','FontSize',8);
                [data1,data2] = writefactortable(app.data_update.TDoutput, app.data_update.PDoutput);
                annotation(f,'textbox',[0.63 0.16 0.3 0.03],'EdgeColor',[0.24 0.7 0.44],'LineWidth',2);
                annotation(f,'textbox',[0.63 0.07 0.3 0.03],'EdgeColor',[0.24 0.7 0.44],'LineWidth',2);

                annotation(f,'textbox',[0.63 0.16 0.35 0.03],'EdgeColor','none', 'String',['副交感神经系统      PNS = ', num2str(PNS)],'FontSize',12,'FontName', '黑体');
                annotation(f,'textbox',[0.63 0.07 0.37 0.03],'EdgeColor','none','String',['交感神经系统          SNS = ', num2str(SNS)],'FontSize',12,'FontName','黑体');

                tableF1 = uitable(f, 'Position', [498 109 242 49],'Data', data1);
                tableF1.RowName={}; tableF1.ColumnName={'舒缓因子1','舒缓因子2','舒缓因子3'}; tableF1.ColumnWidth={80,80,80}; 
                tableF1.FontSize = 12; tableF1.FontName = '黑体';
                tableF2 = uitable(f, 'Position', [498 16 242 48],'Data', data2,'FontSize',12,'FontName','黑体');
                tableF2.RowName={}; tableF2.ColumnName={'压力因子1','压力因子2','压力因子3'}; tableF2.ColumnWidth={80,80,80}; 

                %输出PDF
                exportPDF(app.fileName, app.newpath, f);
            else
                msgbox('请先进行HRV分析')
            end
        end

        % Callback function: PushTool5
        function PushTool5Clicked(app, event)
            % 保存HRV滑动窗口分析结果文件

            % 先判断是否进入编辑状态
            if strcmp(app.Button_3.Enable, 'on')
                exportSlideWindowHRV(app.input_parameter, app.newpath, app.fileName, app.data_update);
                msgbox('HRV滑动窗口分析结果文件保存成功')
            else
                msgbox('请先进行HRV分析')
            end
        end

        % Callback function: PushTool4
        function PushTool4Clicked(app, event)
            % 保存RR间隔文件

            % 先判断是否进入编辑状态
            if strcmp(app.Button_3.Enable, 'on')
                exportRR(app.newpath, app.fileName, IBISample(app.data_update.IBI, app.input_parameter.startTime, app.input_parameter.windowTime));
                msgbox('RR间隔文件保存成功')
            else
                msgbox('请先进行HRV分析')
            end
        end

        % Callback function: PushTool2
        function PushTool2Clicked(app, event)
            % 批量分析

            % 获取界面输入参数
            getFileParameter(app);
            getFolderParameter(app);
            getHRVParameter(app);
            
            % 导入数据
            [newpathOut, flag, app.nameList, app.fileName, ECG, app.Stime] = importFolder(app.newpath, app.input_parameter.Fs);

            % 数据类别
            app.dataTag = 'normal';

            % 加载数据
            firstLoadFolder(app, newpathOut, flag, ECG);
        end

        % Callback function: PushTool6
        function PushTool6Clicked(app, event)
            % 保存批量分析HRV结果

            % 先判断是否进入编辑状态
            if strcmp(app.Button_3.Enable, 'on')
                exportFolderHRV(app.input_parameter, app.nameList, chooseOnlyAlgorithm(app));
                msgbox('批量处理HRV结果文件保存成功')
            else
                msgbox('请先进行HRV分析')
            end
        end

        % Callback function: PushTool10
        function PushTool10Clicked(app, event)
            % 保存批量分析RR结果
            
            % 先判断是否进入编辑状态
            if strcmp(app.Button_3.Enable, 'on')
                exportFolderRR(app.input_parameter, app.nameList, chooseOnlyAlgorithm(app));
                msgbox('批量处理RR结果文件保存成功')
            else
                msgbox('请先进行HRV分析')
            end
        end

        % Value changed function: HzEditField
        function HzEditFieldValueChanged(app, event)
            % 更新采样率
            app.input_parameter.Fs = app.HzEditField.Value;
        end

        % Value changed function: HzEditField_2
        function HzEditField_2ValueChanged(app, event)
            % 更新采样率
            app.input_parameter.Fs = app.HzEditField_2.Value;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            % 是否校正，切换结果
            value = app.DropDown.Value;
            if strcmp(value, '自动校正')
                % 更新图
                refreshECG_RRAxes(app);
                % 显示R峰个数及增减量
                updateRpeakData(app, 'auto->manual');
                % hrv参数结果列表
                refreshTable(app, 'auto->manual');
                % hrv作图
                refreshHRVAxes(app, app.data_update);
            elseif strcmp(value, '无校正')
                % 更新图
                plotUIAxes(app, app.data_ori);
                % 显示R峰个数及增减量
                updateRpeakData(app, 'none->manual');
                % hrv参数结果列表
                refreshTable(app, 'none->manual');
                % hrv作图
                refreshHRVAxes(app, app.data_ori);
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            % 保证分析窗口在数据范围内-文件面板
            value = app.EditField.Value;
            stime = calcTime(value, 'str2time');
            window = stime+app.input_parameter.windowTime;
            if ~isempty(app.ECG_ori)
                data = app.ECG_ori;
            elseif ~isempty(app.PPG_ori)
                data = app.PPG_ori;
            end
            if window <= fix(length(data)/app.input_parameter.Fs)
                app.input_parameter.startTime = stime;  
            else
                app.input_parameter.startTime = fix(length(data)/app.input_parameter.Fs-app.input_parameter.windowTime);
                app.EditField.Value = calcTime(app.input_parameter.startTime, 'time2str');
            end
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            % 保证分析窗口在数据范围内-文件面板
            value = app.EditField_2.Value;
            wtime = calcTime(value, 'str2time');
            window = app.input_parameter.startTime+wtime;
            if ~isempty(app.ECG_ori)
                data = app.ECG_ori;
            elseif ~isempty(app.PPG_ori)
                data = app.PPG_ori;
            end
            totalTime = fix(length(data)/app.input_parameter.Fs);
            if window <= totalTime
                app.input_parameter.windowTime = wtime; 
            else
                app.input_parameter.windowTime = fix(length(data)/app.input_parameter.Fs-app.input_parameter.startTime);
                app.EditField_2.Value = calcTime(app.input_parameter.windowTime, 'time2str');
            end
        end

        % Value changed function: EditField_14
        function EditField_14ValueChanged(app, event)
            % 保证分析窗口在数据范围内-文件夹面板
            value = app.EditField_14.Value;
            stime = calcTime(value, 'str2time');
            window = stime+app.input_parameter.windowTime;
            if window <= fix(length(app.ECG_ori)/app.input_parameter.Fs)
                app.input_parameter.startTime = stime;  
            else
                app.input_parameter.startTime = fix(length(app.ECG_ori)/app.input_parameter.Fs-app.input_parameter.windowTime);
                app.EditField_14.Value = calcTime(app.input_parameter.startTime, 'time2str');
            end
        end

        % Value changed function: EditField_15
        function EditField_15ValueChanged(app, event)
            % 保证分析窗口在数据范围内-文件夹面板
            value = app.EditField_15.Value;
            wtime = calcTime(value, 'str2time');
            window = app.input_parameter.startTime+wtime;
            totalTime = fix(length(app.ECG_ori)/app.input_parameter.Fs);
            if window <= totalTime
                app.input_parameter.windowTime = wtime; 
            else
                app.input_parameter.windowTime = fix(length(app.ECG_ori)/app.input_parameter.Fs-app.input_parameter.startTime);
                app.EditField_15.Value = calcTime(app.input_parameter.windowTime, 'time2str');
            end
        end

        % Value changed function: EditField_16
        function EditField_16ValueChanged(app, event)
            % 更新分析窗口滑动步长
            value = app.EditField_16.Value;
            if value+app.input_parameter.startTime+app.input_parameter.windowTime > fix(length(app.ECG_ori)/app.input_parameter.Fs)
                msgbox('滑动超出数据范围，请正确输入步长')
                app.EditField_16.Value = 30;
            else
                app.input_parameter.step = value;
            end
        end

        % Value changed function: RRCheckBox
        function RRCheckBoxValueChanged(app, event)
            % 读取RR间隔文件
            value = app.RRCheckBox.Value;
            if value == 1
                [newpathOut, flag, RtimeR, Rpeak, IBI] = importRR(app.newpath, app.input_parameter.Fs, app.data_A.ECG);
                if flag
                    app.newpath = newpathOut;
                    % 更新数据集
                    updatePreData(app, 'RR', app.data_A.Rtime, app.data_A.ECG, RtimeR, Rpeak, IBI);
                    % 作图ECG + RR
                    plotUIAxesABCandRR(app, chooseAlgorithm(app));
                end
            else
                % 作图ECG + RR
                plotUIAxesABCandRR(app, chooseAlgorithm(app));
            end
        end

        % Value changed function: ACheckBox
        function ACheckBoxValueChanged(app, event)
            plotUIAxesABCandRR(app, chooseAlgorithm(app));
        end

        % Value changed function: BCheckBox
        function BCheckBoxValueChanged(app, event)
            plotUIAxesABCandRR(app, chooseAlgorithm(app));
        end

        % Value changed function: CCheckBox
        function CCheckBoxValueChanged(app, event)
            plotUIAxesABCandRR(app, chooseAlgorithm(app));  
        end

        % Button pushed function: HRVButton
        function HRVButtonPushed(app, event)
            % 首先判断算法框只能选择一个
            method = chooseOnlyAlgorithm(app);
            if isempty(method)
                msgbox('请选择唯一一种算法！')
            else
                if app.ifFirstHRV
                    switch method
                        case 'A'
                            % 不校正RR
                            % 更新数据集
                            updatePreData(app, 'ori', app.data_A.Rtime, app.data_A.ECG, app.data_A.RtimeR, app.data_A.Rpeak, app.data_A.IBI);
                            
                            % 校正RR
                            % 更新数据集
                            updatePreData(app, 'correction', app.data_A.Rtime, app.data_A.ECG, app.data_A.RtimeR, app.data_A.Rpeak, app.data_A.IBI_correction);
                            updatePreData(app, 'update', app.data_A.Rtime, app.data_A.ECG, app.data_A.RtimeR, app.data_A.Rpeak, app.data_A.IBI_autocorrection);
                        case 'B'
                            % 不校正RR
                            % 更新数据集
                            updatePreData(app, 'ori', app.data_B.Rtime, app.data_B.ECG, app.data_B.RtimeR, app.data_B.Rpeak, app.data_B.IBI);
                            
                            % 校正RR
                            % 更新数据集
                            updatePreData(app, 'correction', app.data_B.Rtime, app.data_B.ECG, app.data_B.RtimeR, app.data_B.Rpeak, app.data_B.IBI_correction);
                            updatePreData(app, 'update', app.data_B.Rtime, app.data_B.ECG, app.data_B.RtimeR, app.data_B.Rpeak, app.data_B.IBI_autocorrection);
%                         case 'C'
%                             % 不校正RR
%                             % 更新数据集
%                             updatePreData(app, 'ori', app.data_C.Rtime, app.data_C.ECG, app.data_C.RtimeR, app.data_C.Rpeak, app.data_C.IBI);
%                             
%                             % 校正RR
%                             % 更新数据集
%                             updatePreData(app, 'correction', app.data_C.Rtime, app.data_C.ECG, app.data_C.RtimeR, app.data_C.Rpeak, app.data_C.IBI_correction);
%                             updatePreData(app, 'update', app.data_C.Rtime, app.data_C.ECG, app.data_C.RtimeR, app.data_C.Rpeak, app.data_C.IBI_correction);
                        case 'RR'
                            % 不校正RR
                            % 更新数据集
                            updatePreData(app, 'ori', app.data_RR.Rtime, app.data_RR.ECG, app.data_RR.RtimeR, app.data_RR.Rpeak, app.data_RR.IBI);
                            
                            % 校正RR
                            % 更新数据集
                            updatePreData(app, 'correction', app.data_RR.Rtime, app.data_RR.ECG, app.data_RR.RtimeR, app.data_RR.Rpeak, app.data_RR.IBI);
                            updatePreData(app, 'update', app.data_RR.Rtime, app.data_RR.ECG, app.data_RR.RtimeR, app.data_RR.Rpeak, app.data_RR.IBI);
                    end
                end
                    
                    if app.ifFirstHRV
                        % 进行hrv分析
                        % 不校正RR
                        [TDoutput, FDoutput, PDoutput, NDoutput, TFoutput] = hrvAnalyse(app.input_parameter, IBISample(app.data_ori.IBI, app.input_parameter.startTime, app.input_parameter.windowTime), 'none');
                        % 更新数据集
                        updateHRVData(app, 'ori', TDoutput, FDoutput, PDoutput, NDoutput, TFoutput);
    
                        % 自动校正RR
                        [TDoutput, FDoutput, PDoutput, NDoutput, TFoutput] = hrvAnalyse(app.input_parameter, IBISample(app.data_correction.IBI, app.input_parameter.startTime, app.input_parameter.windowTime),'corr');
                        % 更新数据集
                        updateHRVData(app, 'correction', TDoutput, FDoutput, PDoutput, NDoutput, TFoutput);
                    end

                    
                    % 手动校正RR
                    [TDoutput, FDoutput, PDoutput, NDoutput, TFoutput] = hrvAnalyse(app.input_parameter, IBISample(app.data_update.IBI, app.input_parameter.startTime, app.input_parameter.windowTime),'corr');
                    updateHRVData(app, 'update', TDoutput, FDoutput, PDoutput, NDoutput, TFoutput);

                    % 作图Resp + HR
                    if ~isempty(app.Resp)
                        plotUIAxesRespAndHR(app, app.data_update, app.Resp);
                    end

                    %SQI计算-信号质量评估
                    switch method
                        case 'A'
                            R_locA = app.data_A.RtimeR *app.input_parameter.Fs;
                            app.SQI = calculateSQI(app.data_A.ECG, app.input_parameter.Fs, app.data_A.IBI_correction, R_locA);
                            %绘制SQI图
                            plotSQI(app)
                        case 'B'
                            R_locB = app.data_B.RtimeR *app.input_parameter.Fs;
                            app.SQI = calculateSQI(app.data_B.ECG, app.input_parameter.Fs, app.data_B.IBI_correction, R_locB);
                            %绘制SQI图
                            plotSQI(app)
                    end

                    %更新呼吸数据
                    updataResp(app,app.Resp);
    
                    % 作图ECG + RR
                    plotUIAxes(app, app.data_update);
                    
                    % 显示R峰个数及增减量
                    updateRpeakData(app, 'auto->manual');
    
                    % hrv参数结果列表
                    refreshTable(app, 'auto->manual');
    
                    % hrv作图
                    refreshHRVAxes(app, app.data_update);
    
                    % 启用编辑功能
                    app.Button_3.Enable = 'on';
                    % 启用无校正与自动校正切换功能
                    app.DropDown.Enable = 'on';
                    % 初次HRV分析置为否
                    app.ifFirstHRV = false;
                    %启用RR-HR绘图切换
                    app.Button_RR.Enable = 'on';
                    app.Button_HR.Enable = 'on';
                    
                    % 开启保存HRV文件功能
                    app.PushTool3.Enable = 'on';
                    %开启保存PDF分析报告功能
                    app.PushexportPDF.Enable = 'on';
                    % 开启保存滑动分析HRV文件功能
                    app.PushTool5.Enable = 'on';
                    % 开启保存RR文件功能
                    app.PushTool4.Enable = 'on';
                    % 开启一屏分析功能
                    app.AnaCheckBox.Enable = 'on';

                    % 关闭反转心电功能
                    app.RevButton.Enable = 'off';
                    % 关闭读取RR文件功能
                    app.RRCheckBox.Enable = 'off';
                    % 关闭选择其他算法功能
                    closeOtherAlgorithm(app);
    
                    msgbox('HRV分析完成')
            end
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            % 编辑按钮状态切换
            value = app.Button_3.Value;
            if value == 1
                app.Button_add.Enable = "on";
                app.Button_delete.Enable = "on";
                app.Spinner.Enable = "on";
            else
                app.Button_add.Enable = "off";
                app.Button_delete.Enable = "off";
                app.Spinner.Enable = "off";

                % 更新hrv分析及结果显示
                [TDoutput, FDoutput, PDoutput, NDoutput, TFoutput] = hrvAnalyse(app.input_parameter, IBISample(app.data_update.IBI, app.input_parameter.startTime, app.input_parameter.windowTime),'corr');
                updateHRVData(app, 'update', TDoutput, FDoutput, PDoutput, NDoutput, TFoutput);
                
                % 更新hrv参数结果列表
                refreshTable(app, 'auto->manual');

                % hrv作图
                refreshHRVAxes(app, app.data_update);
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            % 坐标区鼠标点击事件
            % 获取编辑按钮的状态
            value = app.Button_3.Value;
            if value == 1
                % 获取鼠标点击的横坐标
                currPt = app.UIAxes.CurrentPoint;
                p = currPt(1,1);
                
                % 获取添加删除按钮组的状态
                if app.Button_add.Value == 1
                    % 鼠标添加点
                    [Rtime, ECG, RtimeR, Rpeak, IBI] = addPoints(app.input_parameter.correctRange, app.data_update, p);
                elseif app.Button_delete.Value == 1
                    % 鼠标删除点
                    [Rtime, ECG, RtimeR, Rpeak, IBI] = deletePoints(app.input_parameter.correctRange, app.data_update, p);
                end
                
                % 更新预处理数据集
                updatePreData(app, 'update', Rtime, ECG, RtimeR, Rpeak, IBI);
                % 更新图
                refreshECG_RRAxes(app);
                
                if strcmp(app.DropDown.Value, '自动校正')
                    % 显示R峰个数及增减量
                    updateRpeakData(app, 'auto->manual');
                elseif strcmp(app.DropDown.Value, '无校正')
                    % 显示R峰个数及增减量
                    updateRpeakData(app, 'none->manual');
                end
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            % 坐标轴1滑动控制
            changingValue = event.Value;
            % 坐标轴1最右侧
            if changingValue > app.axes1XLim-app.input_parameter.axes1Range
                changingValue = app.axes1XLim-app.input_parameter.axes1Range;
            end
            app.UIAxes.XLim = [changingValue changingValue + app.input_parameter.axes1Range];
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            % 坐标轴3和4滑动控制
            changingValue = event.Value;
            app.AnaCheckBox.Value = 0; % 重置一屏数据分析选框
            % 坐标轴3和4最右侧
            if changingValue > app.axes3XLim-app.input_parameter.axes3Range
                changingValue = app.axes3XLim-app.input_parameter.axes3Range;
            end
            app.UIAxes_3.XLim = [changingValue changingValue + app.input_parameter.axes3Range];
            app.UIAxes_4.XLim = [changingValue changingValue + app.input_parameter.axes3Range];
        end

        % Value changed function: AnaCheckBox
        function AnaCheckBoxValueChanged(app, event)
            % 呼吸一屏数据分析
            value = app.AnaCheckBox.Value;
            if value == 1
                left = app.Slider_2.Value;
                right = left + app.XEditField_2.Value;

                % 更新hrv分析及结果显示
                [TDoutput, FDoutput, PDoutput, NDoutput, TFoutput] = hrvAnalyse(app.input_parameter, IBISample(app.data_update.IBI, left, right),'corr');
                updateHRVData(app, 'update', TDoutput, FDoutput, PDoutput, NDoutput, TFoutput);
                
                % 更新hrv参数结果列表
                refreshTable(app, 'auto->manual');

                % hrv作图
                refreshHRVAxes(app, app.data_update);
            end
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            % 编辑修正模糊范围
            value = app.Spinner.Value;
            app.input_parameter.correctRange = value * app.input_parameter.Fs;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            % 向左微调坐标轴（左移一页）
            XLimLeft = app.UIAxes.XLim(1) - app.input_parameter.axes1Range;
            XLimRight = app.UIAxes.XLim(2) - app.input_parameter.axes1Range;
            if XLimRight > 0
                app.UIAxes.XLim = [XLimLeft XLimRight];
                if XLimLeft < 0
                    app.Slider.Value = 0;
                else
                    app.Slider.Value = (XLimLeft + XLimRight)/2;
                end
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            % 向左微调坐标轴（左移一页）
            XLimLeft = app.UIAxes_3.XLim(1) - app.input_parameter.axes3Range;
            XLimRight = app.UIAxes_3.XLim(2) - app.input_parameter.axes3Range;
            if XLimRight > 0
                app.UIAxes_3.XLim = [XLimLeft XLimRight];
                app.UIAxes_4.XLim = [XLimLeft XLimRight];
                if XLimLeft < 0
                    app.Slider_2.Value = 0;
                else
                    app.Slider_2.Value = (XLimLeft + XLimRight)/2;
                end
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            % 向右微调坐标轴（右移一页）
            XLimLeft = app.UIAxes.XLim(1) + app.input_parameter.axes1Range;
            XLimRight = app.UIAxes.XLim(2) + app.input_parameter.axes1Range;
            if XLimLeft < app.axes1XLim
                app.UIAxes.XLim = [XLimLeft XLimRight];
                if (XLimLeft + XLimRight)/2 > app.axes1XLim
                    app.Slider.Value = app.axes1XLim;
                else
                    app.Slider.Value = (XLimLeft + XLimRight)/2;
                end
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            % 向右微调坐标轴（右移一页）
            XLimLeft = app.UIAxes_3.XLim(1) + app.input_parameter.axes3Range;
            XLimRight = app.UIAxes_3.XLim(2) + app.input_parameter.axes3Range;
            if XLimLeft < app.axes3XLim
                app.UIAxes_3.XLim = [XLimLeft XLimRight];
                app.UIAxes_4.XLim = [XLimLeft XLimRight];
                if (XLimLeft + XLimRight)/2 > app.axes3XLim
                    app.Slider_2.Value = app.axes3XLim;
                else
                    app.Slider_2.Value = (XLimLeft + XLimRight)/2;
                end
            end
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            % 坐标轴1显示范围变化
            value = app.XEditField.Value;
            app.input_parameter.axes1Range = value;
            if isempty(app.data_update)
                if isempty(app.data_RR)
                    plotUIAxesABC(app);
                else
                    plotUIAxesRR(app);
                end
            else
                plotUIAxes(app, app.data_update);
            end
        end

        % Value changed function: XEditField_2
        function XEditField_2ValueChanged(app, event)
            % 坐标轴3、4显示范围变化
            value = app.XEditField_2.Value;
            app.input_parameter.axes3Range = value;
            if ~isempty(app.Resp)
                plotUIAxesRespAndHR(app, app.data_update, app.Resp)
            end
            
        end

        % Value changed function: pNNxsEditField
        function pNNxsEditFieldValueChanged(app, event)
            app.input_parameter.pNNxIndex = app.pNNxsEditField.Value;
        end

        % Value changed function: SDNNiminEditField
        function SDNNiminEditFieldValueChanged(app, event)
            app.input_parameter.SDNNiIndex = 60*app.SDNNiminEditField.Value;   
        end

        % Value changed function: VLFEditField
        function VLFEditFieldValueChanged(app, event)
            app.input_parameter.welch.VLF(1) = app.VLFEditField.Value;  
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            app.input_parameter.welch.VLF(2) = app.EditField_5.Value;
        end

        % Value changed function: LFEditField
        function LFEditFieldValueChanged(app, event)
            app.input_parameter.welch.LF(1) = app.LFEditField.Value;
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            app.input_parameter.welch.LF(2) = app.EditField_6.Value;
        end

        % Value changed function: HFEditField
        function HFEditFieldValueChanged(app, event)
            app.input_parameter.welch.HF(1) = app.HFEditField.Value;
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            app.input_parameter.welch.HF(2) = app.EditField_7.Value;
        end

        % Value changed function: InterpolationRateHzEditField
        function InterpolationRateHzEditFieldValueChanged(app, event)
            app.input_parameter.welch.fs = app.InterpolationRateHzEditField.Value;
        end

        % Value changed function: PointsinPSDEditField
        function PointsinPSDEditFieldValueChanged(app, event)
            app.input_parameter.welch.nfft = app.PointsinPSDEditField.Value;
        end

        % Value changed function: WidthptsEditField
        function WidthptsEditFieldValueChanged(app, event)
            app.input_parameter.welch.window = app.WidthptsEditField.Value;
        end

        % Value changed function: OverlapEditField
        function OverlapEditFieldValueChanged(app, event)
            app.input_parameter.welch.noverlap = app.OverlapEditField.Value;
        end

        % Value changed function: VLFEditField_2
        function VLFEditField_2ValueChanged(app, event)
            app.input_parameter.ar.VLF(1) = app.VLFEditField_2.Value;          
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            app.input_parameter.ar.VLF(2) = app.EditField_9.Value;
        end

        % Value changed function: LFEditField_2
        function LFEditField_2ValueChanged(app, event)
            app.input_parameter.ar.LF(1) = app.LFEditField_2.Value;
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            app.input_parameter.ar.LF(2) = app.EditField_10.Value;
        end

        % Value changed function: HFEditField_2
        function HFEditField_2ValueChanged(app, event)
            app.input_parameter.ar.HF(1) = app.HFEditField_2.Value;
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            app.input_parameter.ar.HF(2) = app.EditField_11.Value;
        end

        % Value changed function: InterpolationRateHzEditField_2
        function InterpolationRateHzEditField_2ValueChanged(app, event)
            app.input_parameter.ar.fs = app.InterpolationRateHzEditField_2.Value;
        end

        % Value changed function: PointsinPSDEditField_2
        function PointsinPSDEditField_2ValueChanged(app, event)
            app.input_parameter.ar.nfft = app.PointsinPSDEditField_2.Value;
        end

        % Value changed function: BMOEditField_2
        function BMOEditField_2ValueChanged(app, event)
            app.input_parameter.ar.AR_order = app.BMOEditField_2.Value;
        end

        % Value changed function: SampEnrEditField
        function SampEnrEditFieldValueChanged(app, event)
            app.input_parameter.r = app.SampEnrEditField.Value;    
        end

        % Value changed function: mEditField
        function mEditFieldValueChanged(app, event)
            app.input_parameter.m = app.mEditField.Value;
        end

        % Value changed function: DFAnEditField
        function DFAnEditFieldValueChanged(app, event)
            app.input_parameter.n1 = app.DFAnEditField.Value;
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            app.input_parameter.n2 = app.EditField_8.Value;
        end

        % Value changed function: BreakPointEditField
        function BreakPointEditFieldValueChanged(app, event)
            app.input_parameter.breakpoint = app.BreakPointEditField.Value;
        end

        % Value changed function: Button_add
        function Button_addValueChanged(app, event)
            % 校正添加
            app.Button_add.Value = 1;
            app.Button_delete.Value = 0;
        end

        % Value changed function: Button_delete
        function Button_deleteValueChanged(app, event)
            % 校正删除
            app.Button_add.Value = 0;
            app.Button_delete.Value = 1;
        end

        % Value changed function: tfOverlapsEditField
        function tfOverlapsEditFieldValueChanged(app, event)
            app.input_parameter.TF.noverlap = app.tfOverlapsEditField.Value;
        end

        % Value changed function: tfWidowsEditField
        function tfWidowsEditFieldValueChanged(app, event)
            app.input_parameter.TF.window = app.tfWidowsEditField.Value;
        end

        % Value changed function: Button_RR
        function Button_RRValueChanged(app, event)
            %RR图绘制
            app.Button_RR.Value = 1;
            app.Button_HR.Value = 0;

            plotUIAxes(app, app.data_update);
        end

        % Value changed function: Button_HR
        function Button_HRValueChanged(app, event)
            %HR图绘制
            app.Button_HR.Value = 1;
            app.Button_RR.Value = 0;

            plotUIAxes(app, app.data_update);
        end

        % Selection changed function: MethodButtonGroup_S
        function MethodButtonGroup_SSelectionChanged(app, event)
            Methods = chooseTFmethod(app,'s');
            IBI_sample = IBISample(app.data_update.IBI, app.input_parameter.startTime, app.input_parameter.windowTime);
                %时频绘图
                plotSpectrogram(app.UIAxesTF_S,app.input_parameter.Fs,app.data_update.TFoutput, app.input_parameter.ar.VLF, ...
                    app.input_parameter.ar.LF, app.input_parameter.ar.HF, [],[0 0.6], true,Methods, IBI_sample)
        end

        % Selection changed function: MethodButtonGroup_W
        function MethodButtonGroup_WSelectionChanged(app, event)
            IBI_sample = IBISample(app.data_update.IBI, app.input_parameter.startTime, app.input_parameter.windowTime);
            plotUIAxesTF(app, app.data_update, IBI_sample)
        end

        % Selection changed function: PlotViewButtonGroup
        function PlotViewButtonGroupSelectionChanged(app, event)
            IBI_sample = IBISample(app.data_update.IBI, app.input_parameter.startTime, app.input_parameter.windowTime);
            plotUIAxesTF(app, app.data_update, IBI_sample) 
            selectButton = app.PlotViewButtonGroup.SelectedObject;
            if selectButton == app.WaterfallButton
                app.WaveletButton_W.Enable = false;
            else
                app.WaveletButton_W.Enable = true;
                if selectButton ==app.LFHFRatioButton && selectButton ==app.LFHFPowerButton
                    cla(app.UIAxesTF_W,"reset")
                end
            end
        end

        % Button pushed function: applyMethod_Button
        function applyMethod_ButtonPushed(app, event)
            %获取当前算法选项
            if app.ACheckBox.Value
                tag = 'A';
                IBI_data = app.data_A.IBI_autocorrection;
            elseif app.BCheckBox.Value
                tag = 'B';
                IBI_data = app.data_B.IBI_autocorrection;
            else
                tag = 'RR';
                IBI_data = app.data_RR.IBI;
            end
            %获得自动校正面板所选择参数
            getIBIvararginMethod(app);
            [recorrectrdIBI,~] = preProcessIBI(IBI_data,  'locateMethod', app.IBIvarargin.locateMethod, ...
                'locateInput', app.IBIvarargin.locateInput, 'replaceMethod', app.IBIvarargin.replaceMethod, 'replaceInput', str2num(app.IBIvarargin.replaceInput)*app.input_parameter.Fs);

            %校正结果返回当前算法存储结果中,原自动校正算法用于绘制对比图
            if app.ACheckBox.Value
                app.data_A.IBI_autocorrection = recorrectrdIBI;
                app.data_A.IBI_correction = IBI_data;
                app.data_A.RtimeRauto = recorrectrdIBI(:, 1);
                app.data_A.Rpeakauto = app.data_A.ECG(round(recorrectrdIBI(1:end, 1)*app.input_parameter.Fs));

                updatePreData(app, 'correction', app.data_A.Rtime, app.data_A.ECG, app.data_A.RtimeRauto, app.data_A.Rpeakauto, app.data_A.IBI_correction);
                updatePreData(app, 'update', app.data_A.Rtime, app.data_A.ECG, app.data_A.RtimeRauto, app.data_A.Rpeakauto, app.data_A.IBI_autocorrection);
            elseif app.BCheckBox.Value
                app.data_B.IBI_autocorrection = recorrectrdIBI;
                app.data_B.IBI_correction = IBI_data;
                 app.data_B.RtimeRauto = recorrectrdIBI(:, 1);
                app.data_B.Rpeakauto = app.data_B.ECG(round(recorrectrdIBI(1:end, 1)*app.input_parameter.Fs));

                updatePreData(app, 'correction', app.data_B.Rtime, app.data_B.ECG, app.data_B.RtimeRauto, app.data_B.Rpeakauto, app.data_B.IBI_correction);
                updatePreData(app, 'update', app.data_B.Rtime, app.data_B.ECG, app.data_B.RtimeRauto, app.data_B.Rpeakauto, app.data_B.IBI_autocorrection);
            else
                app.data_RR.IBI = recorrectrdIBI;
                app.data_RR.IBI_correction = IBI_data;
            end
            plotUIAxesABCandRR(app,tag);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HRVenusUIFigure = uifigure('Visible', 'off');
            app.HRVenusUIFigure.Name = 'HRVenus';
            app.HRVenusUIFigure.Icon = '蜡笔小新.jpg';
            app.Menu = uimenu(app.HRVenusUIFigure);
            app.Menu.Text = '菜单';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.Text = '退出';
            app.Menu_2 = uimenu(app.HRVenusUIFigure);
            app.Menu_2.Text = '视图';
            app.Menu_4 = uimenu(app.HRVenusUIFigure);
            app.Menu_4.Text = '工具';
            app.Menu_5 = uimenu(app.HRVenusUIFigure);
            app.Menu_5.Text = '帮助';
            app.Toolbar = uitoolbar(app.HRVenusUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'读取心电'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = '读取心电文件.png';
            app.PushTool11 = uipushtool(app.Toolbar);
            app.PushTool11.ClickedCallback = createCallbackFcn(app, @PushTool11Clicked, true);
            app.PushTool11.Icon = fullfile(pathToMLAPP, '导入PPG.jpg');
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'保存HRV结果'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Enable = 'off';
            app.PushTool3.Icon = '保存HRV分析结果.png';
            app.PushexportPDF = uipushtool(app.Toolbar);
            app.PushexportPDF.ClickedCallback = createCallbackFcn(app, @PushexportPDFClicked, true);
            app.PushexportPDF.Enable = 'off';
            app.PushexportPDF.Icon = '保存PDF分析报告.png';
            app.PushTool5 = uipushtool(app.Toolbar);
            app.PushTool5.Tooltip = {'保存滑动分析结果'};
            app.PushTool5.ClickedCallback = createCallbackFcn(app, @PushTool5Clicked, true);
            app.PushTool5.Enable = 'off';
            app.PushTool5.Icon = '保存滑动分析结果.png';
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Tooltip = {'保存RR间隔文件'};
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @PushTool4Clicked, true);
            app.PushTool4.Enable = 'off';
            app.PushTool4.Icon = '保存RR间隔文件.png';
            app.PushTool4.Separator = 'on';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'打开文件夹'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = '打开文件夹.png';
            app.PushTool2.Separator = 'on';
            app.PushTool6 = uipushtool(app.Toolbar);
            app.PushTool6.Tooltip = {'保存批量分析结果'};
            app.PushTool6.ClickedCallback = createCallbackFcn(app, @PushTool6Clicked, true);
            app.PushTool6.Enable = 'off';
            app.PushTool6.Icon = '保存批量分析结果.png';
            app.PushTool10 = uipushtool(app.Toolbar);
            app.PushTool10.Tooltip = {'保存批量RR结果'};
            app.PushTool10.ClickedCallback = createCallbackFcn(app, @PushTool10Clicked, true);
            app.PushTool10.Enable = 'off';
            app.PushTool10.Icon = '保存RR间隔文件.png';
            app.TabGroup = uitabgroup(app.HRVenusUIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '时域分析';
            app.UIAxes2 = uiaxes(app.Tab);
            xlabel(app.UIAxes2, 'RR(s)')
            app.UIAxes2.Tag = 'TimeAxes1';
            app.UIAxes2_2 = uiaxes(app.Tab);
            xlabel(app.UIAxes2_2, 'HR(beats/min)')
            app.UIAxes2_2.Tag = 'TimeAxes2';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'变量'; '值(无/自动校正)'; '值(手动校正)'; '单位'};
            app.UITable.RowName = {};
            app.UITable.Tag = 'TimeTable';
            app.pNNxsEditFieldLabel = uilabel(app.Tab);
            app.pNNxsEditFieldLabel.Text = 'pNNx (s) :';
            app.pNNxsEditField = uieditfield(app.Tab, 'numeric');
            app.pNNxsEditField.Limits = [0 Inf];
            app.pNNxsEditField.ValueDisplayFormat = '%.0f';
            app.pNNxsEditField.ValueChangedFcn = createCallbackFcn(app, @pNNxsEditFieldValueChanged, true);
            app.pNNxsEditField.Value = 50;
            app.SDNNiminEditFieldLabel = uilabel(app.Tab);
            app.SDNNiminEditFieldLabel.Text = 'SDNNi (min) :';
            app.SDNNiminEditField = uieditfield(app.Tab, 'numeric');
            app.SDNNiminEditField.Limits = [1 Inf];
            app.SDNNiminEditField.ValueDisplayFormat = '%.0f';
            app.SDNNiminEditField.ValueChangedFcn = createCallbackFcn(app, @SDNNiminEditFieldValueChanged, true);
            app.SDNNiminEditField.Value = 1;
            app.FFTTab = uitab(app.TabGroup);
            app.FFTTab.Title = '频域分析-FFT';
            app.UIAxes2_3 = uiaxes(app.FFTTab);
            title(app.UIAxes2_3, 'Welch')
            xlabel(app.UIAxes2_3, 'Freq(Hz)')
            ylabel(app.UIAxes2_3, 'PSD(s^2/Hz)')
            app.UIAxes2_3.Tag = 'FreqAxes1';
            app.UITable_2 = uitable(app.FFTTab);
            app.UITable_2.ColumnName = {'变量'; '值(无/自动校正)'; '值(手动校正)'; '单位'};
            app.UITable_2.RowName = {};
            app.UITable_2.Tag = 'FreqTable';
            app.VLFEditFieldLabel = uilabel(app.FFTTab);
            app.VLFEditFieldLabel.Text = 'VLF';
            app.VLFEditField = uieditfield(app.FFTTab, 'numeric');
            app.VLFEditField.Limits = [0 Inf];
            app.VLFEditField.ValueChangedFcn = createCallbackFcn(app, @VLFEditFieldValueChanged, true);
            app.Label_9 = uilabel(app.FFTTab);
            app.Label_9.Text = '--';
            app.EditField_5 = uieditfield(app.FFTTab, 'numeric');
            app.EditField_5.Limits = [0 Inf];
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Value = 0.04;
            app.LFEditFieldLabel = uilabel(app.FFTTab);
            app.LFEditFieldLabel.Text = 'LF';
            app.LFEditField = uieditfield(app.FFTTab, 'numeric');
            app.LFEditField.Limits = [0 Inf];
            app.LFEditField.ValueChangedFcn = createCallbackFcn(app, @LFEditFieldValueChanged, true);
            app.LFEditField.Value = 0.04;
            app.EditField_6Label = uilabel(app.FFTTab);
            app.EditField_6Label.Text = '--';
            app.EditField_6 = uieditfield(app.FFTTab, 'numeric');
            app.EditField_6.Limits = [0 Inf];
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Value = 0.15;
            app.HFEditFieldLabel = uilabel(app.FFTTab);
            app.HFEditFieldLabel.Text = 'HF';
            app.HFEditField = uieditfield(app.FFTTab, 'numeric');
            app.HFEditField.Limits = [0 Inf];
            app.HFEditField.ValueChangedFcn = createCallbackFcn(app, @HFEditFieldValueChanged, true);
            app.HFEditField.Value = 0.15;
            app.EditField_7Label = uilabel(app.FFTTab);
            app.EditField_7Label.Text = '--';
            app.EditField_7 = uieditfield(app.FFTTab, 'numeric');
            app.EditField_7.Limits = [0 Inf];
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_7.Value = 0.4;
            app.InterpolationRateHzLabel = uilabel(app.FFTTab);
            app.InterpolationRateHzLabel.Text = 'Interpolation Rate (Hz)';
            app.InterpolationRateHzEditField = uieditfield(app.FFTTab, 'numeric');
            app.InterpolationRateHzEditField.Limits = [0 Inf];
            app.InterpolationRateHzEditField.ValueDisplayFormat = '%.0f';
            app.InterpolationRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @InterpolationRateHzEditFieldValueChanged, true);
            app.InterpolationRateHzEditField.Value = 4;
            app.PointsinPSDEditFieldLabel = uilabel(app.FFTTab);
            app.PointsinPSDEditFieldLabel.Text = 'Points in PSD';
            app.PointsinPSDEditField = uieditfield(app.FFTTab, 'numeric');
            app.PointsinPSDEditField.Limits = [0 Inf];
            app.PointsinPSDEditField.ValueDisplayFormat = '%.0f';
            app.PointsinPSDEditField.ValueChangedFcn = createCallbackFcn(app, @PointsinPSDEditFieldValueChanged, true);
            app.PointsinPSDEditField.Value = 300;
            app.WidthptsEditFieldLabel = uilabel(app.FFTTab);
            app.WidthptsEditFieldLabel.Text = 'Width (pts)';
            app.WidthptsEditField = uieditfield(app.FFTTab, 'numeric');
            app.WidthptsEditField.Limits = [1 Inf];
            app.WidthptsEditField.ValueDisplayFormat = '%.0f';
            app.WidthptsEditField.ValueChangedFcn = createCallbackFcn(app, @WidthptsEditFieldValueChanged, true);
            app.WidthptsEditField.Value = 300;
            app.OverlapEditFieldLabel = uilabel(app.FFTTab);
            app.OverlapEditFieldLabel.Text = 'Overlap (%)';
            app.OverlapEditField = uieditfield(app.FFTTab, 'numeric');
            app.OverlapEditField.Limits = [1 100];
            app.OverlapEditField.ValueDisplayFormat = '%.0f';
            app.OverlapEditField.ValueChangedFcn = createCallbackFcn(app, @OverlapEditFieldValueChanged, true);
            app.OverlapEditField.Value = 50;
            app.ARTab = uitab(app.TabGroup);
            app.ARTab.Title = '频域分析-AR';
            app.UIAxes2_6 = uiaxes(app.ARTab);
            title(app.UIAxes2_6, 'AR')
            xlabel(app.UIAxes2_6, 'Freq(Hz)')
            ylabel(app.UIAxes2_6, 'PSD(s^2/Hz)')
            app.UIAxes2_6.Tag = 'FreqAxes1';
            app.UITable_3 = uitable(app.ARTab);
            app.UITable_3.ColumnName = {'变量'; '值(无/自动校正)'; '值(手动校正)'; '单位'};
            app.UITable_3.RowName = {};
            app.UITable_3.Tag = 'FreqTable';
            app.VLFEditField_2Label = uilabel(app.ARTab);
            app.VLFEditField_2Label.Text = 'VLF';
            app.VLFEditField_2 = uieditfield(app.ARTab, 'numeric');
            app.VLFEditField_2.Limits = [0 Inf];
            app.VLFEditField_2.ValueChangedFcn = createCallbackFcn(app, @VLFEditField_2ValueChanged, true);
            app.EditField_9Label = uilabel(app.ARTab);
            app.EditField_9Label.Text = '--';
            app.EditField_9 = uieditfield(app.ARTab, 'numeric');
            app.EditField_9.Limits = [0 Inf];
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_9.Value = 0.04;
            app.LFEditField_2Label = uilabel(app.ARTab);
            app.LFEditField_2Label.Text = 'LF';
            app.LFEditField_2 = uieditfield(app.ARTab, 'numeric');
            app.LFEditField_2.Limits = [0 Inf];
            app.LFEditField_2.ValueChangedFcn = createCallbackFcn(app, @LFEditField_2ValueChanged, true);
            app.LFEditField_2.Value = 0.04;
            app.EditField_10Label = uilabel(app.ARTab);
            app.EditField_10Label.Text = '--';
            app.EditField_10 = uieditfield(app.ARTab, 'numeric');
            app.EditField_10.Limits = [0 Inf];
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.EditField_10.Value = 0.15;
            app.HFEditField_2Label = uilabel(app.ARTab);
            app.HFEditField_2Label.Text = 'HF';
            app.HFEditField_2 = uieditfield(app.ARTab, 'numeric');
            app.HFEditField_2.Limits = [0 Inf];
            app.HFEditField_2.ValueChangedFcn = createCallbackFcn(app, @HFEditField_2ValueChanged, true);
            app.HFEditField_2.Value = 0.15;
            app.EditField_11Label = uilabel(app.ARTab);
            app.EditField_11Label.Text = '--';
            app.EditField_11 = uieditfield(app.ARTab, 'numeric');
            app.EditField_11.Limits = [0 Inf];
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_11.Value = 0.4;
            app.InterpolationRateHzEditField_2Label = uilabel(app.ARTab);
            app.InterpolationRateHzEditField_2Label.Text = 'Interpolation Rate (Hz)';
            app.InterpolationRateHzEditField_2 = uieditfield(app.ARTab, 'numeric');
            app.InterpolationRateHzEditField_2.Limits = [0 Inf];
            app.InterpolationRateHzEditField_2.ValueDisplayFormat = '%.0f';
            app.InterpolationRateHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @InterpolationRateHzEditField_2ValueChanged, true);
            app.InterpolationRateHzEditField_2.Value = 4;
            app.PointsinPSDEditField_2Label = uilabel(app.ARTab);
            app.PointsinPSDEditField_2Label.Text = 'Points in PSD';
            app.PointsinPSDEditField_2 = uieditfield(app.ARTab, 'numeric');
            app.PointsinPSDEditField_2.Limits = [0 Inf];
            app.PointsinPSDEditField_2.ValueDisplayFormat = '%.0f';
            app.PointsinPSDEditField_2.ValueChangedFcn = createCallbackFcn(app, @PointsinPSDEditField_2ValueChanged, true);
            app.PointsinPSDEditField_2.Value = 300;
            app.BMOEditField_2Label = uilabel(app.ARTab);
            app.BMOEditField_2Label.Text = 'BMO';
            app.BMOEditField_2 = uieditfield(app.ARTab, 'numeric');
            app.BMOEditField_2.Limits = [0 Inf];
            app.BMOEditField_2.ValueDisplayFormat = '%.0f';
            app.BMOEditField_2.ValueChangedFcn = createCallbackFcn(app, @BMOEditField_2ValueChanged, true);
            app.BMOEditField_2.Value = 16;
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '非线性分析';
            app.UIAxes2_4 = uiaxes(app.Tab_4);
            xlabel(app.UIAxes2_4, 'IBI_N (s)')
            ylabel(app.UIAxes2_4, 'IBI_N_+_1 (s)')
            app.UIAxes2_4.Tag = 'NonAxes1';
            app.UIAxes2_5 = uiaxes(app.Tab_4);
            app.UIAxes2_5.Tag = 'NonAxes2';
            app.UITable_4 = uitable(app.Tab_4);
            app.UITable_4.ColumnName = {'变量'; '值(无/自动校正)'; '值(手动校正)'; '单位'};
            app.UITable_4.RowName = {};
            app.UITable_4.Tag = 'NonTable';
            app.SampEnrEditFieldLabel = uilabel(app.Tab_4);
            app.SampEnrEditFieldLabel.Text = 'SampEn   r';
            app.SampEnrEditField = uieditfield(app.Tab_4, 'numeric');
            app.SampEnrEditField.Limits = [0 Inf];
            app.SampEnrEditField.ValueChangedFcn = createCallbackFcn(app, @SampEnrEditFieldValueChanged, true);
            app.SampEnrEditField.Value = 0.2;
            app.mEditFieldLabel = uilabel(app.Tab_4);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.Tab_4, 'numeric');
            app.mEditField.Limits = [0 Inf];
            app.mEditField.ValueDisplayFormat = '%.0f';
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @mEditFieldValueChanged, true);
            app.mEditField.Value = 2;
            app.DFAnEditFieldLabel = uilabel(app.Tab_4);
            app.DFAnEditFieldLabel.Text = 'DFA   n';
            app.DFAnEditField = uieditfield(app.Tab_4, 'numeric');
            app.DFAnEditField.Limits = [0 Inf];
            app.DFAnEditField.ValueDisplayFormat = '%.0f';
            app.DFAnEditField.ValueChangedFcn = createCallbackFcn(app, @DFAnEditFieldValueChanged, true);
            app.DFAnEditField.Value = 4;
            app.EditField_8Label = uilabel(app.Tab_4);
            app.EditField_8Label.Text = '--';
            app.EditField_8 = uieditfield(app.Tab_4, 'numeric');
            app.EditField_8.Limits = [0 Inf];
            app.EditField_8.ValueDisplayFormat = '%.0f';
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_8.Value = 64;
            app.BreakPointEditFieldLabel = uilabel(app.Tab_4);
            app.BreakPointEditFieldLabel.Text = 'Break Point';
            app.BreakPointEditField = uieditfield(app.Tab_4, 'numeric');
            app.BreakPointEditField.Limits = [0 Inf];
            app.BreakPointEditField.ValueDisplayFormat = '%.0f';
            app.BreakPointEditField.ValueChangedFcn = createCallbackFcn(app, @BreakPointEditFieldValueChanged, true);
            app.BreakPointEditField.Value = 13;
            app.TimeFreqTab = uitab(app.TabGroup);
            app.TimeFreqTab.Title = '时频分析';
            app.UIAxesTF_S = uiaxes(app.TimeFreqTab);
            title(app.UIAxesTF_S, 'Spectrogram')
            xlabel(app.UIAxesTF_S, 'time(s)')
            ylabel(app.UIAxesTF_S, 'Freq(Hz)')
            app.UIAxesTF_S.Tag = 'FreqAxes1';
            app.UIAxesTF_W = uiaxes(app.TimeFreqTab);
            title(app.UIAxesTF_W, 'Waterfall')
            xlabel(app.UIAxesTF_W, 'time(s)')
            ylabel(app.UIAxesTF_W, 'Freq(Hz)')
            app.UIAxesTF_W.Tag = 'FreqAxes1';
            app.MethodButtonGroup_S = uibuttongroup(app.TimeFreqTab);
            app.MethodButtonGroup_S.SelectionChangedFcn = createCallbackFcn(app, @MethodButtonGroup_SSelectionChanged, true);
            app.MethodButtonGroup_S.Title = 'Method';
            app.BurgButton_S = uiradiobutton(app.MethodButtonGroup_S);
            app.BurgButton_S.Text = 'Burg';
            app.BurgButton_S.Value = true;
            app.LombScargleButton_S = uiradiobutton(app.MethodButtonGroup_S);
            app.LombScargleButton_S.Text = 'Lomb-Scargle';
            app.WaveletButton_S = uiradiobutton(app.MethodButtonGroup_S);
            app.WaveletButton_S.Text = 'Wavelet';
            app.WidowsEditFieldLabel = uilabel(app.TimeFreqTab);
            app.WidowsEditFieldLabel.Text = 'Widow (s)';
            app.tfWidowsEditField = uieditfield(app.TimeFreqTab, 'numeric');
            app.tfWidowsEditField.Limits = [1 Inf];
            app.tfWidowsEditField.ValueDisplayFormat = '%.0f';
            app.tfWidowsEditField.ValueChangedFcn = createCallbackFcn(app, @tfWidowsEditFieldValueChanged, true);
            app.tfWidowsEditField.Value = 30;
            app.OverlapsEditFieldLabel = uilabel(app.TimeFreqTab);
            app.OverlapsEditFieldLabel.Text = 'Overlap (s)';
            app.tfOverlapsEditField = uieditfield(app.TimeFreqTab, 'numeric');
            app.tfOverlapsEditField.Limits = [1 100];
            app.tfOverlapsEditField.ValueDisplayFormat = '%.0f';
            app.tfOverlapsEditField.ValueChangedFcn = createCallbackFcn(app, @tfOverlapsEditFieldValueChanged, true);
            app.tfOverlapsEditField.Value = 15;
            app.PlotViewButtonGroup = uibuttongroup(app.TimeFreqTab);
            app.PlotViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotViewButtonGroupSelectionChanged, true);
            app.PlotViewButtonGroup.Title = 'Plot View';
            app.SurfaceButton = uitogglebutton(app.PlotViewButtonGroup);
            app.SurfaceButton.Text = 'Surface';
            app.SurfaceButton.Value = true;
            app.WaterfallButton = uitogglebutton(app.PlotViewButtonGroup);
            app.WaterfallButton.Text = 'Waterfall';
            app.LFHFRatioButton = uitogglebutton(app.PlotViewButtonGroup);
            app.LFHFRatioButton.Text = 'LF/HF Ratio';
            app.LFHFPowerButton = uitogglebutton(app.PlotViewButtonGroup);
            app.LFHFPowerButton.Text = 'LF&HF Power';
            app.MethodButtonGroup_W = uibuttongroup(app.TimeFreqTab);
            app.MethodButtonGroup_W.SelectionChangedFcn = createCallbackFcn(app, @MethodButtonGroup_WSelectionChanged, true);
            app.MethodButtonGroup_W.Title = 'Method';
            app.BurgButton_W = uiradiobutton(app.MethodButtonGroup_W);
            app.BurgButton_W.Text = 'Burg';
            app.BurgButton_W.Value = true;
            app.LombScargleButton_W = uiradiobutton(app.MethodButtonGroup_W);
            app.LombScargleButton_W.Text = 'Lomb-Scargle';
            app.WaveletButton_W = uiradiobutton(app.MethodButtonGroup_W);
            app.WaveletButton_W.Text = 'Wavelet';
            app.IBITab = uitab(app.TabGroup);
            app.IBITab.Title = '自动校正';
            app.UIAxes_SQI = uiaxes(app.IBITab);
            title(app.UIAxes_SQI, 'SQI')
            xlabel(app.UIAxes_SQI, 'Time')
            ylabel(app.UIAxes_SQI, 'Correlation')
            zlabel(app.UIAxes_SQI, 'Z')
            app.DetectionButtonGroup = uibuttongroup(app.IBITab);
            app.DetectionButtonGroup.Title = 'Detection';
            app.percentCheckBox = uicheckbox(app.DetectionButtonGroup);
            app.percentCheckBox.Text = 'percent';
            app.stddevCheckBox = uicheckbox(app.DetectionButtonGroup);
            app.stddevCheckBox.Text = 'std dev';
            app.medianCheckBox = uicheckbox(app.DetectionButtonGroup);
            app.medianCheckBox.Text = 'median';
            app.Edit_percent = uieditfield(app.DetectionButtonGroup, 'text');
            app.Edit_percent.Value = '20';
            app.Edit_std = uieditfield(app.DetectionButtonGroup, 'text');
            app.Edit_std.Value = '3';
            app.Edit_median = uieditfield(app.DetectionButtonGroup, 'text');
            app.Edit_median.Value = '4';
            app.Label_33 = uilabel(app.DetectionButtonGroup);
            app.Label_33.Text = '%';
            app.xLabel = uilabel(app.DetectionButtonGroup);
            app.xLabel.Text = 'x';
            app.ReplacementButtonGroup = uibuttongroup(app.IBITab);
            app.ReplacementButtonGroup.Title = 'Replacement';
            app.NoneButton = uiradiobutton(app.ReplacementButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.MeanButton = uiradiobutton(app.ReplacementButtonGroup);
            app.MeanButton.Text = 'Mean';
            app.MedianButton = uiradiobutton(app.ReplacementButtonGroup);
            app.MedianButton.Text = 'Median';
            app.SplineButton = uiradiobutton(app.ReplacementButtonGroup);
            app.SplineButton.Text = 'Spline';
            app.Edit_Mean = uieditfield(app.ReplacementButtonGroup, 'text');
            app.Edit_Mean.Value = '0.3';
            app.Edit_Median = uieditfield(app.ReplacementButtonGroup, 'text');
            app.Edit_Median.Value = '3';
            app.sLabel = uilabel(app.ReplacementButtonGroup);
            app.sLabel.Text = 's';
            app.applyMethod_Button = uibutton(app.IBITab, 'push');
            app.applyMethod_Button.ButtonPushedFcn = createCallbackFcn(app, @applyMethod_ButtonPushed, true);
            app.applyMethod_Button.Text = '应用';
            app.Tab_10 = uitab(app.TabGroup);
            app.Tab_10.Title = '自主神经张力';
            app.UIAxesPNS = uiaxes(app.Tab_10);
            title(app.UIAxesPNS, 'PNS')
            app.UIAxesPNS.XTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.UIAxesPNS.YTick = [];
            app.UIAxesSNS = uiaxes(app.Tab_10);
            title(app.UIAxesSNS, 'SNS')
            app.UIAxesSNS.XDir = 'reverse';
            app.UIAxesSNS.XTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.UIAxesSNS.YTick = [];
            app.TabGroup2 = uitabgroup(app.HRVenusUIFigure);
            app.Tab_5 = uitab(app.TabGroup2);
            app.Tab_5.Title = '文件';
            app.GridLayout = uigridlayout(app.Tab_5);
            app.Label_10 = uilabel(app.GridLayout);
            app.Label_10.Text = '文件名称：';
            app.fileLabel = uilabel(app.GridLayout);
            app.fileLabel.Tag = 'filename';
            app.fileLabel.Text = 'file';
            app.HzEditFieldLabel = uilabel(app.GridLayout);
            app.HzEditFieldLabel.Text = '采样率(Hz)：';
            app.HzEditField = uieditfield(app.GridLayout, 'numeric');
            app.HzEditField.Limits = [1 2000];
            app.HzEditField.ValueDisplayFormat = '%.0f';
            app.HzEditField.ValueChangedFcn = createCallbackFcn(app, @HzEditFieldValueChanged, true);
            app.HzEditField.Tag = 'sampleRate';
            app.HzEditField.Value = 500;
            app.Label_11 = uilabel(app.GridLayout);
            app.Label_11.Text = '数据长度：';
            app.Label_32 = uilabel(app.GridLayout);
            app.Label_32.Tag = 'dataLength';
            app.Label_32.Text = '00:00:00';
            app.Label_16 = uilabel(app.GridLayout);
            app.Label_16.Text = '数据校正：';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'自动校正', '无校正'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Tag = 'beatCorrection';
            app.DropDown.Enable = 'off';
            app.DropDown.Value = '自动校正';
            app.EditField_12Label = uilabel(app.GridLayout);
            app.EditField_12Label.Text = '窗口开始：';
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tag = 'windowStart';
            app.EditField.Editable = 'off';
            app.EditField.Value = '00:00:00';
            app.EditField_13Label = uilabel(app.GridLayout);
            app.EditField_13Label.Text = '窗口时长：';
            app.EditField_2 = uieditfield(app.GridLayout, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Tag = 'windowEnd';
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = '00:00:00';
            app.Label_18 = uilabel(app.GridLayout);
            app.Label_18.Text = '窗口滑动步长(秒)：';
            app.EditField_16 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_16.Limits = [10 Inf];
            app.EditField_16.ValueDisplayFormat = '%.0f';
            app.EditField_16.ValueChangedFcn = createCallbackFcn(app, @EditField_16ValueChanged, true);
            app.EditField_16.Editable = 'off';
            app.EditField_16.Value = 30;
            app.REditFieldLabel = uilabel(app.GridLayout);
            app.REditFieldLabel.Text = 'R峰个数：';
            app.REditField = uieditfield(app.GridLayout, 'text');
            app.REditField.Editable = 'off';
            app.REditField_2Label = uilabel(app.GridLayout);
            app.REditField_2Label.Text = 'R峰增减个数：';
            app.REditField_2 = uieditfield(app.GridLayout, 'text');
            app.REditField_2.Editable = 'off';
            app.Tab_6 = uitab(app.TabGroup2);
            app.Tab_6.Title = '文件夹';
            app.GridLayout_2 = uigridlayout(app.Tab_6);
            app.Label_12 = uilabel(app.GridLayout_2);
            app.Label_12.Text = '文件夹名称：';
            app.folderLabel = uilabel(app.GridLayout_2);
            app.folderLabel.Tag = 'filename';
            app.folderLabel.Text = 'folder';
            app.HzEditField_2Label = uilabel(app.GridLayout_2);
            app.HzEditField_2Label.Text = '采样率(Hz)：';
            app.HzEditField_2 = uieditfield(app.GridLayout_2, 'numeric');
            app.HzEditField_2.Limits = [1 1000];
            app.HzEditField_2.ValueChangedFcn = createCallbackFcn(app, @HzEditField_2ValueChanged, true);
            app.HzEditField_2.Tag = 'sampleRate';
            app.HzEditField_2.Value = 1000;
            app.Label_13 = uilabel(app.GridLayout_2);
            app.Label_13.Text = '数据个数：';
            app.Label_14 = uilabel(app.GridLayout_2);
            app.Label_14.Tag = 'dataLength';
            app.Label_14.Text = '0';
            app.EditField_14Label = uilabel(app.GridLayout_2);
            app.EditField_14Label.Text = '窗口开始：';
            app.EditField_14 = uieditfield(app.GridLayout_2, 'text');
            app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @EditField_14ValueChanged, true);
            app.EditField_14.Tag = 'windowStart';
            app.EditField_14.Editable = 'off';
            app.EditField_14.Value = '00:00:00';
            app.EditField_15Label = uilabel(app.GridLayout_2);
            app.EditField_15Label.Text = '窗口时长：';
            app.EditField_15 = uieditfield(app.GridLayout_2, 'text');
            app.EditField_15.ValueChangedFcn = createCallbackFcn(app, @EditField_15ValueChanged, true);
            app.EditField_15.Tag = 'windowEnd';
            app.EditField_15.Editable = 'off';
            app.EditField_15.Value = '00:00:00';
            app.Label_15 = uilabel(app.GridLayout_2);
            app.Label_15.Text = '首文件名称：';
            app.fileLabel_2 = uilabel(app.GridLayout_2);
            app.fileLabel_2.Tag = 'filename';
            app.fileLabel_2.Text = 'file';
            app.Label_19 = uilabel(app.GridLayout_2);
            app.Label_19.Text = '首文件长度：';
            app.hhmmssLabel_2 = uilabel(app.GridLayout_2);
            app.hhmmssLabel_2.Text = 'hh：mm：ss';
            app.TabGroup3 = uitabgroup(app.HRVenusUIFigure);
            app.Tab_7 = uitab(app.TabGroup3);
            app.Tab_7.Title = '绘图区';
            app.UIAxes = uiaxes(app.Tab_7);
            xlabel(app.UIAxes, 'Time/s')
            ylabel(app.UIAxes, 'ECG/mV')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes.Tag = 'ECGAxes';
            app.UIAxes_2 = uiaxes(app.Tab_7);
            xlabel(app.UIAxes_2, 'Time/min')
            ylabel(app.UIAxes_2, 'RR/s')
            app.UIAxes_2.Tag = 'RRAxes';
            app.ACheckBox = uicheckbox(app.Tab_7);
            app.ACheckBox.ValueChangedFcn = createCallbackFcn(app, @ACheckBoxValueChanged, true);
            app.ACheckBox.Text = 'A';
            app.ACheckBox.Value = true;
            app.BCheckBox = uicheckbox(app.Tab_7);
            app.BCheckBox.ValueChangedFcn = createCallbackFcn(app, @BCheckBoxValueChanged, true);
            app.BCheckBox.Text = 'B';
            app.BCheckBox.Value = true;
            app.CCheckBox = uicheckbox(app.Tab_7);
            app.CCheckBox.ValueChangedFcn = createCallbackFcn(app, @CCheckBoxValueChanged, true);
            app.CCheckBox.Visible = 'off';
            app.CCheckBox.Text = 'C';
            app.RRCheckBox = uicheckbox(app.Tab_7);
            app.RRCheckBox.ValueChangedFcn = createCallbackFcn(app, @RRCheckBoxValueChanged, true);
            app.RRCheckBox.Enable = 'off';
            app.RRCheckBox.Text = 'RR';
            app.HRVButton = uibutton(app.Tab_7, 'push');
            app.HRVButton.ButtonPushedFcn = createCallbackFcn(app, @HRVButtonPushed, true);
            app.HRVButton.Text = 'HRV';
            app.XEditFieldLabel = uilabel(app.Tab_7);
            app.XEditFieldLabel.Text = 'X轴范围：';
            app.XEditField = uieditfield(app.Tab_7, 'numeric');
            app.XEditField.Limits = [1 Inf];
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XEditField.Editable = 'off';
            app.XEditField.Value = 10;
            app.Button_5 = uibutton(app.Tab_7, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '->';
            app.Slider = uislider(app.Tab_7);
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {''};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Tag = 'ECGSlider';
            app.Button_4 = uibutton(app.Tab_7, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '<-';
            app.Button_3 = uibutton(app.Tab_7, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '编辑';
            app.Spinner = uispinner(app.Tab_7);
            app.Spinner.Step = 0.1;
            app.Spinner.Limits = [0 1];
            app.Spinner.ValueDisplayFormat = '%.1f';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Enable = 'off';
            app.Spinner.Value = 0.5;
            app.RevButton = uibutton(app.Tab_7, 'push');
            app.RevButton.ButtonPushedFcn = createCallbackFcn(app, @RevButtonPushed, true);
            app.RevButton.Enable = 'off';
            app.RevButton.Tooltip = {'反转心电信号'};
            app.RevButton.Text = 'Rev';
            app.Button_add = uibutton(app.Tab_7, 'state');
            app.Button_add.ValueChangedFcn = createCallbackFcn(app, @Button_addValueChanged, true);
            app.Button_add.Enable = 'off';
            app.Button_add.Text = '添加';
            app.Button_add.Value = true;
            app.Button_delete = uibutton(app.Tab_7, 'state');
            app.Button_delete.ValueChangedFcn = createCallbackFcn(app, @Button_deleteValueChanged, true);
            app.Button_delete.Enable = 'off';
            app.Button_delete.Text = '删除';
            app.Button_RR = uibutton(app.Tab_7, 'state');
            app.Button_RR.ValueChangedFcn = createCallbackFcn(app, @Button_RRValueChanged, true);
            app.Button_RR.Enable = 'off';
            app.Button_RR.Text = 'RR';
            app.Button_RR.Value = true;
            app.Button_HR = uibutton(app.Tab_7, 'state');
            app.Button_HR.ValueChangedFcn = createCallbackFcn(app, @Button_HRValueChanged, true);
            app.Button_HR.Enable = 'off';
            app.Button_HR.Text = 'HR';
            app.Tab_9 = uitab(app.TabGroup3);
            app.Tab_9.Title = '呼吸心率';
            app.UIAxes_3 = uiaxes(app.Tab_9);
            xlabel(app.UIAxes_3, 'Time/s')
            ylabel(app.UIAxes_3, 'Resp/mV')
            app.UIAxes_3.Tag = 'ECGAxes';
            app.UIAxes_4 = uiaxes(app.Tab_9);
            xlabel(app.UIAxes_4, 'Time/s')
            ylabel(app.UIAxes_4, 'HR/bpm')
            app.UIAxes_4.Tag = 'RRAxes';
            app.Button_6 = uibutton(app.Tab_9, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '->';
            app.Slider_2 = uislider(app.Tab_9);
            app.Slider_2.MajorTicks = [];
            app.Slider_2.MajorTickLabels = {''};
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.MinorTicks = [];
            app.Slider_2.Tag = 'ECGSlider';
            app.Button_7 = uibutton(app.Tab_9, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '<-';
            app.XEditField_2Label = uilabel(app.Tab_9);
            app.XEditField_2Label.Text = 'X轴范围：';
            app.XEditField_2 = uieditfield(app.Tab_9, 'numeric');
            app.XEditField_2.Limits = [1 Inf];
            app.XEditField_2.ValueDisplayFormat = '%.0f';
            app.XEditField_2.ValueChangedFcn = createCallbackFcn(app, @XEditField_2ValueChanged, true);
            app.XEditField_2.Editable = 'off';
            app.XEditField_2.Value = 30;
            app.AnaCheckBox = uicheckbox(app.Tab_9);
            app.AnaCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnaCheckBoxValueChanged, true);
            app.AnaCheckBox.Enable = 'off';
            app.AnaCheckBox.Text = 'Ana';
            % Show the figure after all components are created
            app.HRVenusUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HRVtools_modified(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HRVenusUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HRVenusUIFigure)
        end
    end
end